classdef AriumMS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainWindow                     matlab.ui.Figure
        DEBUGButton                    matlab.ui.control.Button
        DataProcessingPanel            matlab.ui.container.Panel
        ProcessMS2DataCheckBox         matlab.ui.control.CheckBox
        ProcessFilesButton             matlab.ui.control.Button
        RemoveSelectedGroupButton      matlab.ui.control.Button
        CopySettingstoallGroupsButton  matlab.ui.control.Button
        DefineAugmentationandReferencingButton  matlab.ui.control.Button
        SwitchView                     matlab.ui.control.Switch
        SwitchViewLabel                matlab.ui.control.Label
        FeatureScalingPanel            matlab.ui.container.Panel
        FDR                            matlab.ui.control.NumericEditField
        FalseDiscoveryRateLabel        matlab.ui.control.Label
        Hochberg                       matlab.ui.control.CheckBox
        ApplyChangesButton             matlab.ui.control.Button
        minimumFoldChangeEditField     matlab.ui.control.NumericEditField
        minimumFoldChangeEditFieldLabel  matlab.ui.control.Label
        maximumpValueEditField         matlab.ui.control.NumericEditField
        maximumpValueEditFieldLabel    matlab.ui.control.Label
        TransformationDropDown         matlab.ui.control.DropDown
        TransformationDropDownLabel    matlab.ui.control.Label
        ScalingDropDown                matlab.ui.control.DropDown
        ScalingDropDownLabel           matlab.ui.control.Label
        GeneralOptionsPanel            matlab.ui.container.Panel
        SaveButton                     matlab.ui.control.Button
        SelectSaveLoc                  matlab.ui.control.Button
        ImportData                     matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        AriumMSLabel                   matlab.ui.control.Label
    end

    properties (Access = public)
        % Internal States
        groupCounter            {mustBeInteger} = 0
        selectedGroup           {mustBeInteger,mustBePositive} = 1
        currentPlot             (2,:) logical = false
        % Data
        Data                    (1,:) RawData
        transformationType      (1,1) string {mustBeMember(transformationType,["none","logn","log10","power","cube","reciprocal"])} = "none"
        dataScalingType         (1,1) string {mustBeMember(dataScalingType,["none","Centering","Auto","Pareto","Vast","Range","Level"])} = "none"
        maxP                    (1,1) double {mustBeInRange(maxP,0,1)} = 0.05
        minFold                 (1,1) double {mustBePositive} = 2
        mzMergeTol              (1,1) double {mustBePositive} = 0.01
        mzMergeTolUnit          (1,1) string {mustBeMember(mzMergeTolUnit,["Da","ppm"])} = "Da"
        rtMergeTol              (1,1) double {mustBePositive} = 5
        saveLocation            (1,1) string
        dataBaseFile            (1,1) string
        hochbergFilterFlag      (1,1) logical = false
        processMS2Flag          (1,1) logical = false
        falseDiscoveryRate      (1,1) double {mustBeInRange(falseDiscoveryRate,0,1)} = 0.1
        augmentationAssignment  (:,2) categorical
        mergedAssignment        (:,2) categorical
        FeatureData             (1,:) FeatData
        FullFeatureData         (1,1) struct
        colorPalette            (12,3) double
        %Panels
        MainOptionsPanel        (1,:) MainOptions
        ROIOptionsPanel         (1,:) ROIOptions
        IntegrationOptionsPanel (1,:) IntegrationOptions
        ISPanel                 (1,:) ISOptions
        PeakAlignPanel          (1,:) PeakAlignOptions
        MSAlignPanel            (1,:) MSAlignOptions
        BaselinePanel           (1,:) BaseCorrOptions
        GolayPanel              (1,:) GolayOptions
        AddNLossPanel           (1,:) AddNLossOptions
        ScalingPanel            (1,:) SampScaleOptions
        AugmentOptions
        ReportTable
        ResultSubDisplay
        %% Group UI Elements
        % RawData Stage
        TabContainer        (1,1) matlab.ui.container.TabGroup
        Tab                 (1,:) matlab.ui.container.Tab
        GroupLabel          (1,:) matlab.graphics.Graphics
        InfoLabels          (4,:) matlab.graphics.Graphics
        GroupNameField      (1,:) matlab.ui.control.EditField
        SelectFilesButton   (1,:) matlab.ui.control.Button
        PreviewButton       (1,:) matlab.ui.control.Button
        EICButton           (1,:) matlab.ui.control.Button
        PreviewAxes         (1,:) matlab.ui.control.UIAxes
        PreviewTiled        (1,:) matlab.graphics.layout.TiledChartLayout
        PreviewChrom        (1,:) matlab.graphics.Graphics
        PreviewMZ           (1,:) matlab.graphics.Graphics
        % evaluation plots
        VulcanoPlot         (1,:) matlab.graphics.axis.Axes
        PCA3Dplot           (1,1) matlab.graphics.axis.Axes
        PCAMultiplot        (1,2) matlab.graphics.layout.TiledChartLayout
        ScatterPlot         (1,:) matlab.graphics.axis.Axes
        CloudPlot           (1,:) matlab.graphics.axis.Axes
        % FeatData Stage
        EvaluationPanel     EvaluationOptions
        DataBase            MSDataBase


    end
    methods (Access = private)
        %% Constructor Functions
        function app = TabSelection(app,src,event)
            % identify current tab and construct new one when + tab is selected
            t = event.NewValue;
            app.selectedGroup = t.UserData;
            if t.Title == '+'
                delete(app.TabContainer.Children(end))
                app.Data(app.groupCounter+1) = RawData(app.groupCounter+1);
                app.constructNewTab;
                uitab(src,'Title','+',Tooltip="Add additional group");
                app.TabContainer.SelectedTab = app.Tab(app.groupCounter);
                app.selectedGroup = app.TabContainer.SelectedTab.UserData;
            end
        end

        function app = constructNewTab(app)
            app.groupCounter = app.groupCounter + 1;
            if app.groupCounter > 1
                app.RemoveSelectedGroupButton.Enable = 'on';
            end
            %construct new Tab
            app.Tab(app.groupCounter) = uitab(app.TabContainer,'Title',"Group " + app.groupCounter,'UserData',app.groupCounter);
            app.GroupNameField(app.groupCounter) = uieditfield(app.Tab(app.groupCounter),"text",...
                Position=[10,610,110,20],...
                CharacterLimits=[1,16],...
                Value="Group " + app.groupCounter,...
                HorizontalAlignment="center",...
                Tooltip="Change the group name");
            app.GroupNameField(app.groupCounter).ValueChangedFcn = @(src,event) {GroupNameChange(app,src,event,app.selectedGroup)};
            app.GroupLabel(1,app.groupCounter) = uilabel(app.Tab(app.groupCounter),"Text","Group Label:",...
                "Position",[10,630,110,20],...
                "HorizontalAlignment","center",...
                "VerticalAlignment","center");
            app.SelectFilesButton(app.groupCounter) = uibutton(app.Tab(app.groupCounter),...
                "Text","Select Files",...
                "Position",[10,565,110,40]);
            app.SelectFilesButton(app.groupCounter).ButtonPushedFcn = @(src,event) {SelectFiles(app,src,event,app.selectedGroup)};
            %build preview Parameter Button
            app.PreviewButton(app.groupCounter) = uibutton(app.Tab(app.groupCounter),...
                Position=[385,565,75,50],...
                Text=["Preview"; "Parameters"],...
                Visible=false);
            app.PreviewButton(app.groupCounter).ButtonPushedFcn = @(src,event) {TestParameters(app,src,event,app.selectedGroup)};
            app.EICButton(app.groupCounter) = uibutton(app.Tab(app.groupCounter),...
                Position=[300,565,75,50],...
                Text=["Plot"; "EICs"],...
                Visible=false);
            app.EICButton(app.groupCounter).ButtonPushedFcn = @(src,event) {PlotEIC(app,src,event,app.selectedGroup)};

            addMainOptions(app,app.groupCounter)
            addROIOptions(app,app.groupCounter)
            addIntegrationOptions(app,app.groupCounter);
            addPeakAlignPanel(app,app.groupCounter);
            addMSAlignPanel(app,app.groupCounter);
            addScalingPanel(app,app.groupCounter);
            addAddNLossPanel(app,app.groupCounter);
            addGolayPanel(app,app.groupCounter);
            addBaselinePanel(app,app.groupCounter);
            addISPanel(app,app.groupCounter)
            app.PreviewAxes(app.groupCounter) = uiaxes(app.Tab(app.groupCounter),OuterPosition=[470 200 680 420],PositionConstraint="outerposition",Visible="off");
            app.PreviewTiled(app.groupCounter) = tiledlayout(app.Tab(app.groupCounter),2,1,"TileSpacing","compact",Padding="compact",Units="pixels",OuterPosition=[470 200 680 420],PositionConstraint="outerposition",Visible="off");
            app.currentPlot(1:2,app.groupCounter) = false;
            app.InfoLabels(:,app.groupCounter) = gobjects(4,1);
        end

        function addMainOptions(app,CurrentTab)
            %construct UI elements
            app.MainOptionsPanel(CurrentTab) = MainOptions(app.Tab(CurrentTab),app.Data(CurrentTab));
            %add callbackfcn
            app.MainOptionsPanel(CurrentTab).FileTypeSwitch.ValueChangedFcn = @(src,event) {FileTypeChange(app,src,event,CurrentTab)};
            app.MainOptionsPanel(CurrentTab).BlankTickBox.ValueChangedFcn = @(src,event) {BlankChange(app,src,event,CurrentTab)};
            app.MainOptionsPanel(CurrentTab).PeakAlignmentTickBox.ValueChangedFcn = @(src,event) {PeakAlignmentChange(app,src,event,CurrentTab)};
            app.MainOptionsPanel(CurrentTab).MSAlignmentTickBox.ValueChangedFcn = @(src,event) {MSAlignmentChange(app,src,event,CurrentTab)};
            app.MainOptionsPanel(CurrentTab).BaseCorrTickBox.ValueChangedFcn = @(src,event) {BaseCorrChange(app,src,event,CurrentTab)};
            app.MainOptionsPanel(CurrentTab).GolayTickBox.ValueChangedFcn = @(src,event) {GolayChange(app,src,event,CurrentTab)};
            app.MainOptionsPanel(CurrentTab).ISTDTickBox.ValueChangedFcn = @(src,event) {ISTDChange(app,src,event,CurrentTab)};
            app.MainOptionsPanel(CurrentTab).IsotopeFilterTickBox.ValueChangedFcn = @(src,event) {IsoChange(app,src,event,CurrentTab)};
            app.MainOptionsPanel(CurrentTab).ScalingTickBox.ValueChangedFcn = @(src,event) {ScaleChange(app,src,event,CurrentTab)};
            app.MainOptionsPanel(CurrentTab).AddNLossTickBox.ValueChangedFcn = @(src,event) {AddNLossChange(app,src,event,CurrentTab)};
            app.MainOptionsPanel(CurrentTab).ContaminantTickBox.ValueChangedFcn = @(src,event) {ContaminantChange(app,src,event,CurrentTab)};
        end

        function addROIOptions(app,CurrentTab)
            %construct UI elements
            app.ROIOptionsPanel(CurrentTab) = ROIOptions(app.Tab(CurrentTab),app.Data(CurrentTab));
            %add Callback functions
            app.ROIOptionsPanel(CurrentTab).ThreshField.ValueChangedFcn = @(src,event) {ThreshChange(app,src,event,CurrentTab)};
            app.ROIOptionsPanel(CurrentTab).mzerrorField.ValueChangedFcn = @(src,event) {mzerrorChange(app,src,event,CurrentTab)};
            app.ROIOptionsPanel(CurrentTab).mzerrorUnitDrop.ValueChangedFcn = @(src,event) {mzerrorUnitChange(app,src,event,CurrentTab)};
            app.ROIOptionsPanel(CurrentTab).minROIField.ValueChangedFcn = @(src,event) {minROIChange(app,src,event,CurrentTab)};
            app.ROIOptionsPanel(CurrentTab).StartTimeField.ValueChangedFcn = @(src,event) {STimeChange(app,src,event,CurrentTab)};
            app.ROIOptionsPanel(CurrentTab).EndTimeField.ValueChangedFcn = @(src,event) {ETimeChange(app,src,event,CurrentTab)};
        end

        function addIntegrationOptions(app,CurrentTab)
            %construct UI elements
            app.IntegrationOptionsPanel(CurrentTab) = IntegrationOptions(app.Tab(CurrentTab),app.Data(CurrentTab));
            %add Callback functions
            app.IntegrationOptionsPanel(CurrentTab).EntropyCheck.ValueChangedFcn = @(src,event) {EntropyChange(app,src,event,CurrentTab)};
            app.IntegrationOptionsPanel(CurrentTab).EntropyDrop.ValueChangedFcn = @(src,event) {EntropyStrengthChange(app,src,event,CurrentTab)};
            app.IntegrationOptionsPanel(CurrentTab).OccurrenceField.ValueChangedFcn = @(src,event) {OccurrenceChange(app,src,event,CurrentTab)};
            app.IntegrationOptionsPanel(CurrentTab).minSNField.ValueChangedFcn = @(src,event) {minSNChange(app,src,event,CurrentTab)};
            app.IntegrationOptionsPanel(CurrentTab).maxWidthField.ValueChangedFcn = @(src,event) {maxWidthChange(app,src,event,CurrentTab)};
            app.IntegrationOptionsPanel(CurrentTab).minWidthField.ValueChangedFcn = @(src,event) {minWidthChange(app,src,event,CurrentTab)};
            app.IntegrationOptionsPanel(CurrentTab).EvalParamDrop.ValueChangedFcn = @(src,event) {EvalParamChange(app,src,event,CurrentTab)};
            app.IntegrationOptionsPanel(CurrentTab).mzTolField.ValueChangedFcn = @(src,event) {mzTolChange(app,src,event,CurrentTab)};
            app.IntegrationOptionsPanel(CurrentTab).mzTolUnitDrop.ValueChangedFcn = @(src,event) {mzTolUnitChange(app,src,event,CurrentTab)};
            app.IntegrationOptionsPanel(CurrentTab).RTTolField.ValueChangedFcn = @(src,event) {RTTolChange(app,src,event,CurrentTab)};
        end

        function addMSAlignPanel(app,CurrentTab)
            %construct UI elements
            app.MSAlignPanel(CurrentTab) = MSAlignOptions(app.Tab(CurrentTab),app.Data(CurrentTab));
            %add Callback functions
            app.MSAlignPanel(CurrentTab).QuantilField.ValueChangedFcn = @(src,event) {QuantilChange(app,src,event,CurrentTab)};
            app.MSAlignPanel(CurrentTab).EstimationDrop.ValueChangedFcn = @(src,event) {EstimChange(app,src,event,CurrentTab)};
            app.MSAlignPanel(CurrentTab).CorrectionDrop.ValueChangedFcn = @(src,event) {CorrChange(app,src,event,CurrentTab)};
        end

        function addPeakAlignPanel(app,CurrentTab)
            %construct UI elements
            app.PeakAlignPanel(CurrentTab) = PeakAlignOptions(app.Tab(CurrentTab),app.Data(CurrentTab));
            %add Callback functions
            app.PeakAlignPanel(CurrentTab).MaxShiftFieldPos.ValueChangedFcn = @(src,event) {MaxShiftPosChange(app,src,event,CurrentTab)};
            app.PeakAlignPanel(CurrentTab).MaxShiftFieldNeg.ValueChangedFcn = @(src,event) {MaxShiftNegChange(app,src,event,CurrentTab)};
            app.PeakAlignPanel(CurrentTab).PulseWidthField.ValueChangedFcn = @(src,event) {PulseWidthChange(app,src,event,CurrentTab)};
            app.PeakAlignPanel(CurrentTab).WindowSizeField.ValueChangedFcn = @(src,event) {WindowSizeRatioChange(app,src,event,CurrentTab)};
            app.PeakAlignPanel(CurrentTab).SearchSpaceDrop.ValueChangedFcn = @(src,event) {SearchSpaceChange(app,src,event,CurrentTab)};
            app.PeakAlignPanel(CurrentTab).IterationField.ValueChangedFcn = @(src,event) {IterationChange(app,src,event,CurrentTab)};
            app.PeakAlignPanel(CurrentTab).GridStepField.ValueChangedFcn = @(src,event) {GridStepChange(app,src,event,CurrentTab)};
        end

        function addBaselinePanel(app,CurrentTab)
            %construct UI elements
            app.BaselinePanel(CurrentTab) = BaseCorrOptions(app.Tab(CurrentTab),app.Data(CurrentTab));
            %add Callback functions
            app.BaselinePanel(CurrentTab).WindowSizeField.ValueChangedFcn = @(src,event) {WindowSizeChange(app,src,event,CurrentTab)};
            app.BaselinePanel(CurrentTab).StepSizeField.ValueChangedFcn = @(src,event) {StepSizeChange(app,src,event,CurrentTab)};
            app.BaselinePanel(CurrentTab).RegressionDrop.ValueChangedFcn = @(src,event) {RegressionChange(app,src,event,CurrentTab)};
            app.BaselinePanel(CurrentTab).EstimationDrop.ValueChangedFcn = @(src,event) {EstimationChange(app,src,event,CurrentTab)};
            app.BaselinePanel(CurrentTab).SmoothDrop.ValueChangedFcn = @(src,event) {SmoothChange(app,src,event,CurrentTab)};
            app.BaselinePanel(CurrentTab).QuantilValField.ValueChangedFcn = @(src,event) {QuantilValChange(app,src,event,CurrentTab)};
        end

        function addGolayPanel(app,CurrentTab)
            %construct UI elements
            app.GolayPanel(CurrentTab) = GolayOptions(app.Tab(CurrentTab),app.Data(CurrentTab));
            %add Callback functions
            app.GolayPanel(CurrentTab).Degree.ValueChangedFcn = @(src,event) {DegreeChanged(app,src,event,CurrentTab)};
            app.GolayPanel(CurrentTab).FrameSize.ValueChangedFcn = @(src,event) {FrameSizeChanged(app,src,event,CurrentTab)};
        end

        function addScalingPanel(app,CurrentTab)
            app.ScalingPanel(CurrentTab) = SampScaleOptions(app.Tab(CurrentTab));
            %add Callback functions
            app.ScalingPanel(CurrentTab).SelectButton.ButtonPushedFcn = @(src,event) {ConstructScalingFigure(app,src,event,CurrentTab)};
        end

        function addISPanel(app,CurrentTab)
            %construct UI elements
            app.ISPanel(CurrentTab) = ISOptions(app.Tab(CurrentTab),app.Data(CurrentTab));
            %add Callback functions
            app.ISPanel(CurrentTab).DefineButton.ButtonPushedFcn = @(src,event) {ConstructISFigure(app,src,event,CurrentTab)};
            app.ISPanel(CurrentTab).NumberSelect.ValueChangedFcn = @(src,event) {ISLampAction(app,src,event,CurrentTab)};
            app.ISPanel(CurrentTab).MassCalCheck.ValueChangedFcn = @(src,event) {MassCalChange(app,src,event,CurrentTab)};
            app.ISPanel(CurrentTab).ApplyDrop.ValueChangedFcn = @(src,event) {ISApplyChange(app,src,event,CurrentTab)};
            app.ISPanel(CurrentTab).OrderDrop.ValueChangedFcn = @(src,event) {ISOrderChange(app,src,event,CurrentTab)};
        end

        function addAddNLossPanel(app,CurrentTab)
            app.AddNLossPanel(CurrentTab) = AddNLossOptions(app.Tab(CurrentTab));
            %add Callback functions
            app.AddNLossPanel(CurrentTab).SelectButton.ButtonPushedFcn = @(src,event) {ConstructAddNLossFigure(app,src,event,CurrentTab)};
        end

        function addEvaluationPanel(app)
            % construct UI elements
            app.EvaluationPanel(1) = EvaluationOptions(app);
            %add Callback functions
            app.EvaluationPanel.TICButton.ButtonPushedFcn = @(src,event) {PlotTICs(app,src,event)};
            app.EvaluationPanel.ROIButton.ButtonPushedFcn = @(src,event) {PlotROIs(app,src,event)};
            app.EvaluationPanel.IntegrationButton.ButtonPushedFcn = @(src,event) {PlotIntResults(app,src,event)};
            app.EvaluationPanel.EntropyButton.ButtonPushedFcn = @(src,event) {PlotEntropy(app,src,event)};
            app.EvaluationPanel.SNButton.ButtonPushedFcn = @(src,event) {PlotSN(app,src,event)};
            app.EvaluationPanel.RejectedButton.ButtonPushedFcn = @(src,event) {PlotRejects(app,src,event)};
            app.EvaluationPanel.ScatterButton.ButtonPushedFcn = @(src,event) {PlotScatter(app,src,event)};
            app.EvaluationPanel.CloudButton.ButtonPushedFcn = @(src,event) {PlotCloud(app,src,event)};
            app.EvaluationPanel.ProbabilityButton.ButtonPushedFcn = @(src,event) {PlotProbability(app,src,event)};
            app.EvaluationPanel.DistributionButton.ButtonPushedFcn = @(src,event) {PlotFeatureDistribution(app,src,event)};
            app.EvaluationPanel.pDistributionButton.ButtonPushedFcn = @(src,event) {PlotpDistribution(app,src,event)};
            app.EvaluationPanel.IsMassDeltaButton.ButtonPushedFcn = @(src,event) {PlotISdeviation(app,src,event)};
            app.EvaluationPanel.VolcanoButton.ButtonPushedFcn = @(src,event) {PlotVolcano(app,src,event)};
            app.EvaluationPanel.HeatmapButton.ButtonPushedFcn = @(src,event) {PlotHeatmap(app,src,event)};
            app.EvaluationPanel.PCAButton.ButtonPushedFcn = @(src,event) {PlotPCA(app,src,event)};
            app.EvaluationPanel.DatabaseButton.ButtonPushedFcn = @(src,event) {OpenDatabaseWindow(app,src,event)};
            app.EvaluationPanel.ExportButton.ButtonPushedFcn = @(src,event) {ExportResults(app,src,event)};
        end

        %% App Functions
        function UpdateCallbacks(app,CurrentTab)
            %MainCallbacks
            app.MainOptionsPanel(CurrentTab).FileTypeSwitch.ValueChangedFcn = @(src,event) {FileTypeChange(app,src,event,CurrentTab)};
            app.MainOptionsPanel(CurrentTab).BlankTickBox.ValueChangedFcn = @(src,event) {BlankChange(app,src,event,CurrentTab)};
            app.MainOptionsPanel(CurrentTab).PeakAlignmentTickBox.ValueChangedFcn = @(src,event) {PeakAlignmentChange(app,src,event,CurrentTab)};
            app.MainOptionsPanel(CurrentTab).MSAlignmentTickBox.ValueChangedFcn = @(src,event) {MSAlignmentChange(app,src,event,CurrentTab)};
            app.MainOptionsPanel(CurrentTab).BaseCorrTickBox.ValueChangedFcn = @(src,event) {BaseCorrChange(app,src,event,CurrentTab)};
            app.MainOptionsPanel(CurrentTab).GolayTickBox.ValueChangedFcn = @(src,event) {GolayChange(app,src,event,CurrentTab)};
            app.MainOptionsPanel(CurrentTab).ISTDTickBox.ValueChangedFcn = @(src,event) {ISTDChange(app,src,event,CurrentTab)};
            app.MainOptionsPanel(CurrentTab).IsotopeFilterTickBox.ValueChangedFcn = @(src,event) {IsoChange(app,src,event,CurrentTab)};
            app.MainOptionsPanel(CurrentTab).ScalingTickBox.ValueChangedFcn = @(src,event) {ScaleChange(app,src,event,CurrentTab)};
            app.MainOptionsPanel(CurrentTab).AddNLossTickBox.ValueChangedFcn = @(src,event) {AddNLossChange(app,src,event,CurrentTab)};
            app.MainOptionsPanel(CurrentTab).ContaminantTickBox.ValueChangedFcn = @(src,event) {ContaminantChange(app,src,event,CurrentTab)};
            %ROI Callbacks
            app.ROIOptionsPanel(CurrentTab).ThreshField.ValueChangedFcn = @(src,event) {ThreshChange(app,src,event,CurrentTab)};
            app.ROIOptionsPanel(CurrentTab).mzerrorField.ValueChangedFcn = @(src,event) {mzerrorChange(app,src,event,CurrentTab)};
            app.ROIOptionsPanel(CurrentTab).mzerrorUnitDrop.ValueChangedFcn = @(src,event) {mzerrorUnitChange(app,src,event,CurrentTab)};
            app.ROIOptionsPanel(CurrentTab).minROIField.ValueChangedFcn = @(src,event) {minROIChange(app,src,event,CurrentTab)};
            app.ROIOptionsPanel(CurrentTab).StartTimeField.ValueChangedFcn = @(src,event) {STimeChange(app,src,event,CurrentTab)};
            app.ROIOptionsPanel(CurrentTab).EndTimeField.ValueChangedFcn = @(src,event) {ETimeChange(app,src,event,CurrentTab)};
            %Integration Callbacks
            app.IntegrationOptionsPanel(CurrentTab).EntropyCheck.ValueChangedFcn = @(src,event) {EntropyChange(app,src,event,CurrentTab)};
            app.IntegrationOptionsPanel(CurrentTab).EntropyDrop.ValueChangedFcn = @(src,event) {EntropyStrengthChange(app,src,event,CurrentTab)};
            app.IntegrationOptionsPanel(CurrentTab).OccurrenceField.ValueChangedFcn = @(src,event) {OccurrenceChange(app,src,event,CurrentTab)};
            app.IntegrationOptionsPanel(CurrentTab).minSNField.ValueChangedFcn = @(src,event) {minSNChange(app,src,event,CurrentTab)};
            app.IntegrationOptionsPanel(CurrentTab).maxWidthField.ValueChangedFcn = @(src,event) {maxWidthChange(app,src,event,CurrentTab)};
            app.IntegrationOptionsPanel(CurrentTab).minWidthField.ValueChangedFcn = @(src,event) {minWidthChange(app,src,event,CurrentTab)};
            app.IntegrationOptionsPanel(CurrentTab).EvalParamDrop.ValueChangedFcn = @(src,event) {EvalParamChange(app,src,event,CurrentTab)};
            app.IntegrationOptionsPanel(CurrentTab).mzTolField.ValueChangedFcn = @(src,event) {mzTolChange(app,src,event,CurrentTab)};
            app.IntegrationOptionsPanel(CurrentTab).mzTolUnitDrop.ValueChangedFcn = @(src,event) {mzTolUnitChange(app,src,event,CurrentTab)};
            app.IntegrationOptionsPanel(CurrentTab).RTTolField.ValueChangedFcn = @(src,event) {RTTolChange(app,src,event,CurrentTab)};
            %MSAlign Callbacks
            app.MSAlignPanel(CurrentTab).QuantilField.ValueChangedFcn = @(src,event) {QuantilChange(app,src,event,CurrentTab)};
            app.MSAlignPanel(CurrentTab).EstimationDrop.ValueChangedFcn = @(src,event) {EstimChange(app,src,event,CurrentTab)};
            app.MSAlignPanel(CurrentTab).CorrectionDrop.ValueChangedFcn = @(src,event) {CorrChange(app,src,event,CurrentTab)};
            %PeakAlign Callbacks
            app.PeakAlignPanel(CurrentTab).MaxShiftFieldPos.ValueChangedFcn = @(src,event) {MaxShiftPosChange(app,src,event,CurrentTab)};
            app.PeakAlignPanel(CurrentTab).MaxShiftFieldNeg.ValueChangedFcn = @(src,event) {MaxShiftNegChange(app,src,event,CurrentTab)};
            app.PeakAlignPanel(CurrentTab).PulseWidthField.ValueChangedFcn = @(src,event) {PulseWidthChange(app,src,event,CurrentTab)};
            app.PeakAlignPanel(CurrentTab).WindowSizeField.ValueChangedFcn = @(src,event) {WindowSizeRatioChange(app,src,event,CurrentTab)};
            app.PeakAlignPanel(CurrentTab).SearchSpaceDrop.ValueChangedFcn = @(src,event) {SearchSpaceChange(app,src,event,CurrentTab)};
            app.PeakAlignPanel(CurrentTab).IterationField.ValueChangedFcn = @(src,event) {IterationChange(app,src,event,CurrentTab)};
            app.PeakAlignPanel(CurrentTab).GridStepField.ValueChangedFcn = @(src,event) {GridStepChange(app,src,event,CurrentTab)};
            %Baseline Callbacks
            app.BaselinePanel(CurrentTab).WindowSizeField.ValueChangedFcn = @(src,event) {WindowSizeChange(app,src,event,CurrentTab)};
            app.BaselinePanel(CurrentTab).StepSizeField.ValueChangedFcn = @(src,event) {StepSizeChange(app,src,event,CurrentTab)};
            app.BaselinePanel(CurrentTab).RegressionDrop.ValueChangedFcn = @(src,event) {RegressionChange(app,src,event,CurrentTab)};
            app.BaselinePanel(CurrentTab).EstimationDrop.ValueChangedFcn = @(src,event) {EstimationChange(app,src,event,CurrentTab)};
            app.BaselinePanel(CurrentTab).SmoothDrop.ValueChangedFcn = @(src,event) {SmoothChange(app,src,event,CurrentTab)};
            app.BaselinePanel(CurrentTab).QuantilValField.ValueChangedFcn = @(src,event) {QuantilValChange(app,src,event,CurrentTab)};
            %Golay Callbacks
            app.GolayPanel(CurrentTab).Degree.ValueChangedFcn = @(src,event) {DegreeChanged(app,src,event,CurrentTab)};
            app.GolayPanel(CurrentTab).FrameSize.ValueChangedFcn = @(src,event) {FrameSizeChanged(app,src,event,CurrentTab)};
            %Scaling Callback
            app.ScalingPanel(CurrentTab).SelectButton.ButtonPushedFcn = @(src,event) {ConstructScalingFigure(app,src,event,CurrentTab)};
            %IS Callbacks
            app.ISPanel(CurrentTab).DefineButton.ButtonPushedFcn = @(src,event) {ConstructISFigure(app,src,event,CurrentTab)};
            app.ISPanel(CurrentTab).NumberSelect.ValueChangedFcn = @(src,event) {ISLampAction(app,src,event,CurrentTab)};
            app.ISPanel(CurrentTab).MassCalCheck.ValueChangedFcn = @(src,event) {MassCalChange(app,src,event,CurrentTab)};
            app.ISPanel(CurrentTab).ApplyDrop.ValueChangedFcn = @(src,event) {ISApplyChange(app,src,event,CurrentTab)};
            app.ISPanel(CurrentTab).OrderDrop.ValueChangedFcn = @(src,event) {ISOrderChange(app,src,event,CurrentTab)};
            %AddNLoss Callback
            app.AddNLossPanel(CurrentTab).SelectButton.ButtonPushedFcn = @(src,event) {ConstructAddNLossFigure(app,src,event,CurrentTab)};
            %Tab Elements
            app.SelectFilesButton(CurrentTab).ButtonPushedFcn = @(src,event) {SelectFiles(app,src,event,CurrentTab)};
            app.PreviewButton(CurrentTab).ButtonPushedFcn = @(src,event) {TestParameters(app,src,event,CurrentTab)};
            app.EICButton(CurrentTab).ButtonPushedFcn = @(src,event) {PlotEIC(app,src,event,CurrentTab)};
            app.GroupNameField(CurrentTab).ValueChangedFcn = @(src,event) {GroupNameChange(app,src,event,CurrentTab)};
        end

        function CheckReady(app)
            data=app.Data;
            if size(data,2) > 1
                app.DefineAugmentationandReferencingButton.Enable = "on";
                app.CopySettingstoallGroupsButton.Enable = "on";
                app.RemoveSelectedGroupButton.Enable = "on";
            else
                app.DefineAugmentationandReferencingButton.Enable = "off";
                app.CopySettingstoallGroupsButton.Enable = "off";
                app.RemoveSelectedGroupButton.Enable = "off";
            end
            CheckSum=zeros(5,1);
            %check for missing Files
            GroupCheck = true(size(data));
            DataCheckSum = ~(cellfun(@isempty, {data.FileNames})); % true: contains files; false: no files
            CheckSum(1) = sum(GroupCheck-DataCheckSum);
            %Check for missing Blank Files
            BLKCheckSum = [data.BLKSubtraction]; % true: contains files; false: no files
            BLKDatTest = ~(cellfun(@isempty, {data.BlankFiles})); % true: contains files; false: no files
            BLKCheckSum = BLKCheckSum-BLKDatTest;
            BLKCheckSum(BLKCheckSum==-1)=false;
            CheckSum(2) = sum(BLKCheckSum);
            %Check for missing IS Data
            ISCheckSum = [data.ISTDCorr];
            ISDatTest= ~(cellfun(@isempty, {data.ISDat})); % true: contains files; false: no files
            ISCheckSum = ISCheckSum-ISDatTest;
            ISCheckSum(ISCheckSum==-1)=false;
            CheckSum(3) = sum(ISCheckSum);
            %Check for unspecified AddNLos options
            AddNlossCheck = [data.AdductFilter];
            AddNlossCheckSum=zeros(size(AddNlossCheck));
            for n=1:numel(AddNlossCheck)
                if AddNlossCheck(n) == 1
                    AddNlossDat=app.AddNLossPanel(n).AddNLossLamp.Color(1);
                    AddNlossDat = AddNlossDat(1) == 0.39; %Check if Lamp is green
                    AddNlossCheckSum(n) = AddNlossCheck(n)-AddNlossDat;
                else
                    AddNlossCheckSum(n) = 0;
                end
            end
            CheckSum(4) = sum(AddNlossCheckSum);

            %Check for unspecified Scaling factors
            SampScaleCheck = [data.ScalingCorr];
            SampScaleCheckSum=zeros(size(SampScaleCheck));
            for n=1:numel(SampScaleCheck)
                if SampScaleCheck(n) == 1
                    ScalingDat=app.ScalingPanel(n).ScalingLamp.Color(1);
                    ScalingDat = ScalingDat(1) == 0.39; %Check if Lamp is green
                    SampScaleCheckSum(n) = SampScaleCheck(n)-ScalingDat;
                else
                    SampScaleCheckSum(n) = 0;
                end
            end
            CheckSum(5) = sum(SampScaleCheckSum);

            if sum(CheckSum) == 0
                app.ProcessFilesButton.Enable = "on";
            else
                app.ProcessFilesButton.Enable = "off";
            end
        end
        function CheckDataState(app)
            % Check if preview Data is availible
            emp=false;
            counter = 1;
            while emp == false && counter<=size(app.Data,2)
                emp = isempty(app.Data(counter).RawDataFileObj.PreviewTICs);
                counter = counter + 1;
            end
            if emp == false
                app.EvaluationPanel.TICButton.Enable = "on";
                if isempty(app.FeatureData) == false
                    app.EvaluationPanel.ScatterButton.Enable = "on";
                end
            else
                app.EvaluationPanel.TICButton.Enable = "off";
                app.EvaluationPanel.ScatterButton.Enable = "off";
            end

            %check if ROI was generated
            emp=false;
            counter = 1;
            while emp == false && counter<=size(app.Data,2)
                emp = isempty(app.Data(counter).ROIDataFile);
                counter = counter + 1;
            end
            if emp == false
                app.EvaluationPanel.ROIButton.Enable = "on";
                app.EvaluationPanel.IntegrationButton.Enable = "on";
                app.EvaluationPanel.EntropyButton.Enable = "on";
                app.EvaluationPanel.SNButton.Enable = "on";
                app.EvaluationPanel.RejectedButton.Enable = "on";
            else
                app.EvaluationPanel.ROIButton.Enable = "off";
                app.EvaluationPanel.IntegrationButton.Enable = "off";
                app.EvaluationPanel.EntropyButton.Enable = "off";
                app.EvaluationPanel.SNButton.Enable = "off";
                app.EvaluationPanel.RejectedButton.Enable = "off";
            end

            %check if IS was found
            emp = false(1,width(app.Data));
            for n = 1:width(app.Data)
                emp(n) = isempty(app.Data(n).ISdelta);
            end
            if ~any(emp)
                app.EvaluationPanel.IsMassDeltaButton.Enable = "on";
            else
                app.EvaluationPanel.IsMassDeltaButton.Enable = "off";
            end

            %check if FeatData was generated
            if isempty(app.FeatureData) == false
                app.EvaluationPanel.DistributionButton.Enable = "on";
                app.EvaluationPanel.ProbabilityButton.Enable = "on";
                app.EvaluationPanel.HeatmapButton.Enable = "on";
                app.EvaluationPanel.ExportButton.Enable = "on";
                app.EvaluationPanel.DatabaseButton.Enable = "on";
                app.EvaluationPanel.IntegrationButton.Enable = "on";
                app.ApplyChangesButton.Enable = "on";

                %check for more than one group
                if numel(app.FeatureData.GroupName) > 1
                    app.EvaluationPanel.pDistributionButton.Enable = "on";
                    app.EvaluationPanel.CloudButton.Enable = "on";
                    app.EvaluationPanel.VolcanoButton.Enable = "on";
                    app.EvaluationPanel.PCAButton.Enable = "on";
                else
                    app.EvaluationPanel.pDistributionButton.Enable = "off";
                    app.EvaluationPanel.CloudButton.Enable = "off";
                    app.EvaluationPanel.VolcanoButton.Enable = "off";
                    app.EvaluationPanel.PCAButton.Enable = "off";
                end
            else
                app.EvaluationPanel.ScatterButton.Enable = "off";
                app.EvaluationPanel.DistributionButton.Enable = "off";
                app.EvaluationPanel.ProbabilityButton.Enable = "off";
                app.EvaluationPanel.HeatmapButton.Enable = "off";
                app.EvaluationPanel.ExportButton.Enable = "off";
                app.EvaluationPanel.DatabaseButton.Enable = "off";
                app.EvaluationPanel.pDistributionButton.Enable = "off";
                app.EvaluationPanel.CloudButton.Enable = "off";
                app.EvaluationPanel.VolcanoButton.Enable = "off";
                app.EvaluationPanel.PCAButton.Enable = "off";
                app.EvaluationPanel.IntegrationButton.Enable = "off";
                app.ApplyChangesButton.Enable = "off";
            end

        end
        function PreviewFile(app,CurrentTab)
            d = uiprogressdlg(app.MainWindow,'Title','Plotting Graphs','Message','Please Wait','Indeterminate','on');
            %Gather Data
            BPC = app.Data(CurrentTab).RawDataFileObj.PreviewBPCs;
            TIC = app.Data(CurrentTab).RawDataFileObj.PreviewTICs;
            times = app.Data(CurrentTab).RawDataFileObj.PreviewTimes;
            % delete old Plot
            if app.currentPlot(1,CurrentTab) == true
                delete(app.PreviewAxes(CurrentTab).Children)
                delete(app.PreviewAxes(CurrentTab))
                app.currentPlot(1,CurrentTab)=false;
            elseif app.currentPlot(2,CurrentTab) == true
                delete(app.PreviewTiled(CurrentTab).Children)
                delete(app.PreviewTiled(CurrentTab))
                app.currentPlot(2,CurrentTab)=false;
            end
            % build new Plot
            app.PreviewAxes(CurrentTab) = uiaxes(app.Tab(CurrentTab),OuterPosition=[470 200 680 420],PositionConstraint="outerposition",Visible="off");
            colororder(app.PreviewAxes(CurrentTab),"gem12")
            hold(app.PreviewAxes(CurrentTab),"on")
            for n=1:size(times,1)
                plot(app.PreviewAxes(CurrentTab),times{n},TIC{n})
                app.PreviewAxes(CurrentTab).Children(1).DataTipTemplate.DataTipRows(1).Label="RT:";
                app.PreviewAxes(CurrentTab).Children(1).DataTipTemplate.DataTipRows(2).Label="Total ion intensity:";
            end
            for n=1:size(times,1)
                plot(app.PreviewAxes(CurrentTab),times{n},BPC{n})
                app.PreviewAxes(CurrentTab).Children(1).DataTipTemplate.DataTipRows(1).Label="RT:";
                app.PreviewAxes(CurrentTab).Children(1).DataTipTemplate.DataTipRows(2).Label="Basepeak intensity:";
            end
            hold(app.PreviewAxes(CurrentTab),"off")
            TICLegend = "TIC: " + app.Data(CurrentTab).FileNames;
            BPCLegend = "BPC: " + app.Data(CurrentTab).FileNames;
            app.PreviewAxes(CurrentTab).Title.String="Preview";
            legend(app.PreviewAxes(CurrentTab),[TICLegend;BPCLegend],Interpreter="none")
            ylabel(app.PreviewAxes(CurrentTab),"Intensity [arb]")
            xlabel(app.PreviewAxes(CurrentTab),"Time [sec]")
            app.PreviewAxes(CurrentTab).Visible = "on";
            drawnow
            close(d)
            % change Plot State
            app.currentPlot(1,CurrentTab)=true;
        end

        function ActivateParameterPanels(app)
            delete(app.TabContainer.Children(1:end))
            delete(app.Tab(1:end))
            app.groupCounter = 0;
            for CurrentTab=1:size(app.Data,2)
                app.constructNewTab;
                app.Tab(CurrentTab).Title = app.Data(CurrentTab).GroupName;
                app.GroupNameField(CurrentTab).Value = app.Data(CurrentTab).GroupName;
                addMainOptions(app,CurrentTab)
                addROIOptions(app,CurrentTab)
                addIntegrationOptions(app,CurrentTab)
                app.MainOptionsPanel(CurrentTab).Container.Enable = true;
                app.ROIOptionsPanel(CurrentTab).Container.Enable = true;
                app.IntegrationOptionsPanel(CurrentTab).Container.Enable = true;
                addPeakAlignPanel(app,CurrentTab)
                app.PeakAlignPanel(CurrentTab).Container.Enable = app.MainOptionsPanel(CurrentTab).PeakAlignmentTickBox.Value;
                addMSAlignPanel(app,CurrentTab)
                app.MSAlignPanel(CurrentTab).Container.Enable = app.MainOptionsPanel(CurrentTab).MSAlignmentTickBox.Value;
                addBaselinePanel(app,CurrentTab)
                app.BaselinePanel(CurrentTab).Container.Enable = app.MainOptionsPanel(CurrentTab).BaseCorrTickBox.Value;
                addGolayPanel(app,CurrentTab)
                app.GolayPanel(CurrentTab).Container.Enable = app.MainOptionsPanel(CurrentTab).GolayTickBox.Value;
                addScalingPanel(app,CurrentTab)
                app.ScalingPanel(CurrentTab).Container.Enable = app.MainOptionsPanel(CurrentTab).ScalingTickBox.Value;
                addISPanel(app,CurrentTab)
                app.ISPanel(CurrentTab).Container.Enable = app.MainOptionsPanel(CurrentTab).ISTDTickBox.Value;
                addAddNLossPanel(app,CurrentTab)
                app.AddNLossPanel(CurrentTab).Container.Enable = app.MainOptionsPanel(CurrentTab).AddNLossTickBox.Value;
            end
            uitab(app.TabContainer,'Title','+');
            CheckReady(app)
        end

        function mergedStruct = mergeGroups(~,StructArray)
            mergedStruct = StructArray(1);  % Initialize merged struct with first struct from array
            DataSize = arrayfun(@(x) size(x.IntensityStorage),StructArray,'UniformOutput',false);
            DataSize = vertcat(DataSize{:});
            minData = min(DataSize(:,2));
            fieldNames = fieldnames(mergedStruct);
            % Iterate over field names and combine values from struct2 with merged struct
            for n = 2:size(StructArray,2)
                struct2 = StructArray(n);
                for i = 1:numel(fieldNames)
                    fieldName = fieldNames{i};
                    if strcmp(fieldName,'FeatIdentifiers') || strcmp(fieldName,'GroupName')  || strcmp(fieldName,'DataSize') || strcmp(fieldName,'FoundInGroup')
                        mergedStruct.(fieldName) = [mergedStruct.(fieldName); struct2.(fieldName)];

                    elseif strcmp(fieldName,'SampleNames')
                        mergedStruct.(fieldName) = [mergedStruct.(fieldName)(1:minData,:), struct2.(fieldName)(1:minData,:)];

                    else
                        mergedStruct.(fieldName) = [mergedStruct.(fieldName)(:,1:minData); struct2.(fieldName)(:,1:minData)];
                    end
                end
            end
            mergedStruct.DataSize = DataSize(:,1);
        end

        function MergedStructArray = BuildFullArray(app,StructArray)
            %check groups without features, remove them and throw warning
            %reshape DataSize to row vector
            check = vertcat(StructArray.DataSize)== 0;
            if any(check)
                emptyGroups = [StructArray(check).GroupName];
                emptyGroups = emptyGroups(1,:);
                StructArray(check)=[];
                %remove empty groups from augmentation
                app.augmentationAssignment(check,:)=[];
                %display warning
                header="Groups without Features identified";
                message = ["Process again with less strict settings","Affected Group(s):",emptyGroups];
                uialert(app.MainWindow,message,header,'Icon','warning');

            end
            %gather Tolerance
            mzTol = app.mzMergeTol;
            mzTolUnit = app.mzMergeTolUnit;
            RTtol = app.rtMergeTol;
            % Initialize Merged Struct
            MergedStructArray = struct('GroupName',{},'FeatIdentifiers',{},'FoundInGroup',{},'SampleNames',{},'XIC',{},'IntensityStorage',{},'RetentionTimeStorage',{},'EntropyStorage',{},'Signal2NoiseStorage',{},'DataSize',{});
            NumberOfFilesArray = zeros(1,size(StructArray,2));
            NumberOfFilesArray(1) = size(StructArray(1).IntensityStorage,2);
            NameArray = strings(2,numel(NumberOfFilesArray));
            NameArray(:,1) = StructArray(1).GroupName;
            SampleNameArray = cell(size(NumberOfFilesArray));
            SampleNameArray{1} = StructArray(1).SampleNames;
            NumFeatureCell = cell(size(NumberOfFilesArray));
            NumFeatureCell{1} = StructArray(1).DataSize;
            for n=2:size(StructArray,2)
                % Define the matrices Struct A
                Identifier_A = StructArray(1).FeatIdentifiers;
                XIC_A = StructArray(1).XIC;
                Intensity_A = StructArray(1).IntensityStorage;
                Retention_A = StructArray(1).RetentionTimeStorage;
                Entropy_A = StructArray(1).EntropyStorage;
                SN_A = StructArray(1).Signal2NoiseStorage;
                InGroup_A = StructArray(1).FoundInGroup;

                % Define the matrices Struct B
                Identifier_B = StructArray(n).FeatIdentifiers;
                XIC_B = StructArray(n).XIC;
                Intensity_B = StructArray(n).IntensityStorage;
                Retention_B = StructArray(n).RetentionTimeStorage;
                Entropy_B = StructArray(n).EntropyStorage;
                SN_B = StructArray(n).Signal2NoiseStorage;
                NumberOfFilesArray(n) = size(Intensity_B,2);
                NameArray(:,n) = StructArray(n).GroupName;
                SampleNameArray{n} = StructArray(n).SampleNames;
                NumFeatureCell{n} = StructArray(n).DataSize;
                InGroup_B = StructArray(n).FoundInGroup;

                % Initialize an empty matrices to store the merged rows
                merged_Identifier = [];
                merged_XIC = [];
                merged_Intensities = [];
                merged_Retention = [];
                merged_SN = [];
                merged_Entropy = [];
                merged_FoundInGroup = [];
                % Iterate over the rows of Mat A
                for i = 1:size(Identifier_A, 1)
                    match_found = false;

                    % Iterate over the rows of Mat B
                    for j = 1:size(Identifier_B, 1)
                        % Check if the identifiers are within the tolerances
                        switch mzTolUnit
                            case "Da"
                                Check = abs(Identifier_A(i, 1) - Identifier_B(j, 1)) <= mzTol;
                            case "ppm"
                                Check = abs((Identifier_A(i, 1)-Identifier_B(j, 1))/Identifier_B(j, 1)*10^6)<=mzTol;
                        end
                        if Check == true && abs(Identifier_A(i, 2) - Identifier_B(j, 2)) <= RTtol
                            merged_Identifier = [merged_Identifier; Identifier_B(j, :)];
                            merged_FoundInGroup = [merged_FoundInGroup; [InGroup_A(i, :), InGroup_B(j, :)]];
                            merged_XIC = [merged_XIC; [XIC_A(i, :), XIC_B(j, :)]];
                            merged_Intensities = [merged_Intensities; [Intensity_A(i, :), Intensity_B(j, :)]];
                            merged_Retention = [merged_Retention; [Retention_A(i, :), Retention_B(j, :)]];
                            merged_Entropy = [merged_Entropy; [Entropy_A(i, :), Entropy_B(j, :)]];
                            merged_SN = [merged_SN; [SN_A(i, :), SN_B(j, :)]];
                            match_found = true;
                            break;
                        end
                    end

                    % If no match is found, append the row from Mat A and IntA with zeros
                    if ~match_found
                        merged_Identifier = [merged_Identifier; Identifier_A(i, :)];
                        merged_FoundInGroup = [merged_FoundInGroup; [InGroup_A(i, :), strings(1, size(InGroup_B, 2))]];
                        merged_XIC = [merged_XIC; [XIC_A(i, :), cell(1, size(XIC_B, 2))]];
                        merged_Intensities = [merged_Intensities; [Intensity_A(i, :), zeros(1, size(Intensity_B, 2))]];
                        merged_Retention = [merged_Retention; [Retention_A(i, :), zeros(1, size(Retention_B, 2))]];
                        merged_Entropy = [merged_Entropy; [Entropy_A(i, :), zeros(1, size(Entropy_B, 2))]];
                        merged_SN = [merged_SN; [SN_A(i, :), zeros(1, size(SN_B, 2))]];
                    end
                end

                % Append the remaining rows from Mat B and IntB (if any)
                for j = 1:size(Identifier_B, 1)
                    if ~ismember(Identifier_B(j, :), merged_Identifier, 'rows')
                        merged_Identifier = [merged_Identifier; Identifier_B(j, :)];
                        merged_FoundInGroup = [merged_FoundInGroup; [strings(1, size(InGroup_A, 2)), InGroup_B(j, :)]];
                        merged_XIC = [merged_XIC; [cell(1, size(XIC_A, 2)), XIC_B(j, :)]];
                        merged_Intensities = [merged_Intensities; [zeros(1, size(Intensity_A, 2)), Intensity_B(j, :)]];
                        merged_Retention = [merged_Retention; [zeros(1, size(Retention_A, 2)), Retention_B(j, :)]];
                        merged_Entropy = [merged_Entropy; [zeros(1, size(Entropy_A, 2)), Entropy_B(j, :)]];
                        merged_SN = [merged_SN; [zeros(1, size(SN_A, 2)), SN_B(j, :)]];
                    end
                end
                StructArray(1).FeatIdentifiers = merged_Identifier;
                StructArray(1).XIC = merged_XIC;
                StructArray(1).IntensityStorage = merged_Intensities;
                StructArray(1).RetentionTimeStorage = merged_Retention;
                StructArray(1).EntropyStorage = merged_Entropy;
                StructArray(1).Signal2NoiseStorage = merged_SN;
                StructArray(1).OriginalGroup = merged_FoundInGroup;
                StructArray(1).FoundInGroup = merged_FoundInGroup;
            end
            MergedStructArray(1).FeatIdentifiers = StructArray(1).FeatIdentifiers;
            MergedStructArray(1).XIC = StructArray(1).XIC;
            MergedStructArray(1).IntensityStorage = StructArray(1).IntensityStorage;
            MergedStructArray(1).RetentionTimeStorage = StructArray(1).RetentionTimeStorage;
            MergedStructArray(1).EntropyStorage = StructArray(1).EntropyStorage;
            MergedStructArray(1).Signal2NoiseStorage = StructArray(1).Signal2NoiseStorage;
            MergedStructArray(1).SampleNumbers = NumberOfFilesArray;
            MergedStructArray(1).GroupName = NameArray;
            MergedStructArray(1).SampleNames = SampleNameArray;
            MergedStructArray(1).OriginalGroup = StructArray(1).OriginalGroup;
            MergedStructArray(1).FoundInGroup = join(StructArray(1).FoundInGroup,",");
        end

        function app = ExportResults(app,~,~)
            %% select save location
            currentFolder = pwd;
            currentFolder = convertCharsToStrings(currentFolder);
            initFile=currentFolder+"\"+"ResultTable";
            [file,path] = uiputfile('*.xlsx','Select Save Location and Filename',initFile);
            figure(app.MainWindow);
            if file==0 % when user cancels input set safe location to current folder and filename to workspace Variable name
                return
            else
                path = convertCharsToStrings(path);
                file = convertCharsToStrings(file);
                outputname=path+file;
            end
            %%
            NameString = app.FeatureData.NameStringArray;
            FoundInGroup = app.FeatureData.InGroup;
            ID = (1:1:length(NameString))';
            % replace inserted average Feature Intensities with 0
            Intensities = app.FeatureData.IntensityArray;
            idx = app.FeatureData.FullUnscaledIntensityArray == 0;
            Intensities(idx) = 0;
            AverageIntensities = app.FeatureData.AverageIntensities;
            STD = app.FeatureData.StandardDeviations;
            fold = horzcat(app.FeatureData.FullFoldChanges{:});
            pVal = horzcat(app.FeatureData.SignificantFeature{:});
            UniqueFeature = app.FeatureData.UniqueFeature;
            FoundInRef = horzcat(app.FeatureData.FoundInReferenceGroup{:});
            OccurenceAll = sum(app.FeatureData.OccurenceCount,2);
            OccurenceInGroup = app.FeatureData.OccurenceCount;
            mz = app.FeatureData.IdentifierArray(:,1);
            RT = app.FeatureData.IdentifierArray(:,2);
            colnames=["ID","Feature","Found in Group","Intensities","Avg. Intensity", "Standard Deviation","Fold Changes","p-Values","Unique Feature in Group","Feature not found in Reference","Occurences in Group","Occurences over all","Mass M [Da]","Retention Time [s]"];
            Out=table(ID,NameString,FoundInGroup,Intensities,AverageIntensities,STD,fold,pVal,UniqueFeature,FoundInRef,OccurenceInGroup,OccurenceAll,mz,RT,'VariableNames',colnames);
            writetable(Out,outputname)
            uialert(app.MainWindow,'Export Complete','Success','Icon','success');
        end

        function app = OpenDatabaseWindow(app,~,~)
            % if isempty(app.DataBase)
            app.addDataBaseWindow;
            % else
            %     app.DataBase.Window.Visible="on";
            % end
        end

        function GenerateReport(app)
            NameString = app.FeatureData.NameStringArray;
            ID = (1:1:length(NameString))';
            % replace inserted average Feature Intensities with 0
            Intensities = app.FeatureData.IntensityArray;
            idx = app.FeatureData.FullUnscaledIntensityArray == 0;
            Intensities(idx) = 0;
            AverageIntensities = app.FeatureData.AverageIntensities;
            STD = app.FeatureData.StandardDeviations;
            fold = horzcat(app.FeatureData.FullFoldChanges{:});
            pVal = horzcat(app.FeatureData.SignificantFeature{:});
            UniqueFeature = app.FeatureData.UniqueFeature;
            FoundInRef = horzcat(app.FeatureData.FoundInReferenceGroup{:});
            OccurenceAll = sum(app.FeatureData.OccurenceCount,2);
            OccurenceInGroup = app.FeatureData.OccurenceCount;
            IdentLevel = app.FeatureData.IdentificationLevel;
            MSSearch = cell(size(ID));

            if ~isempty(app.FeatureData.dataBaseResultsMS1)
                id = cellfun(@isempty,app.FeatureData.dataBaseResultsMS1);
                MSSearch(~id) = app.FeatureData.dataBaseResultsMS1(~id);
            end
            if ~isempty(app.FeatureData.dataBaseResultsMS2)
                id = cellfun(@isempty,app.FeatureData.dataBaseResultsMS2);
                MSSearch(~id) = app.FeatureData.dataBaseResultsMS2(~id);
            end

            mz = app.FeatureData.IdentifierArray(:,1);
            RT = app.FeatureData.IdentifierArray(:,2);
            colnames=["ID","Feature","Avg. Intensity", "Standard Deviation","Fold Changes","p-Values","Identification Level","Database search results","Unique Feature in Group","Feature not found in Reference","Occurences in Group","Occurences over all","Intensities","Mass M [Da]","Retention Time [s]"];
            Out=table(ID,NameString,AverageIntensities,STD,fold,pVal,IdentLevel,MSSearch,UniqueFeature,FoundInRef,OccurenceInGroup,OccurenceAll,Intensities,mz,RT,'VariableNames',colnames);
            report = uifigure("Position",[100,100,1600,600],Name="Result Table");
            app.ReportTable = uitable(report,"Data",Out,Position=[5,5,1590,590]);
            app.ReportTable.ColumnSortable = true;
            app.ReportTable.CellSelectionCallback = @(src,event) {displayCellData(app,src,event)};
        end

        function app = displayCellData(app, ~, eventdata)
            % Get the selected cell indices
            selectedRow = eventdata.Indices(1,1);
            selectedColumn = eventdata.Indices(1,2);
            FeatName = app.FeatureData.NameStringArray(selectedRow);
            % highlight selection in volcano plot
            if ~isempty(app.VulcanoPlot)
                for n=1:size(app.VulcanoPlot,2)
                    if ~isvalid(app.VulcanoPlot(n))
                        continue
                    end
                    index = app.VulcanoPlot(n).Children(end).DataTipTemplate.DataTipRows(3,:).Value == FeatName;
                    % reset highlight
                    if size(app.VulcanoPlot(n).Children(1).XData,2)==1
                        delete(app.VulcanoPlot(n).Children(1));
                    end
                    %plot new highlight
                    if any(index)
                        x = app.VulcanoPlot(n).Children(1).XData(index);
                        y = app.VulcanoPlot(n).Children(1).YData(index);
                        app.VulcanoPlot(n).NextPlot = "add";
                        scatter(app.VulcanoPlot(n),x,y,"red","filled")
                        app.VulcanoPlot(n).Children(1).DataTipTemplate.DataTipRows(1).Label="log2 Fold Change:";
                        app.VulcanoPlot(n).Children(1).DataTipTemplate.DataTipRows(2).Label="-log10 p:";
                        app.VulcanoPlot(n).Children(1).DataTipTemplate.DataTipRows(3)=dataTipTextRow("Feature:",FeatName);
                        app.VulcanoPlot(n).Children(1).DataTipTemplate.DataTipRows(4) = [];
                    end
                end
            end

            % highlight selection in scatter plot
            if ~isempty(app.ScatterPlot)
                for n=1:size(app.ScatterPlot,2)
                    if ~isvalid(app.ScatterPlot(n))
                        continue
                    end
                    % reset highlight
                    if size(app.ScatterPlot(n).Children(1).XData,2)==1
                        while size(app.ScatterPlot(n).Children(1).XData,2)==1
                            delete(app.ScatterPlot(n).Children(1));
                        end
                    end
                    for g=size(app.ScatterPlot(n).Children,1):-1:1
                        index = app.ScatterPlot(n).Children(g).DataTipTemplate.DataTipRows(3,:).Value == FeatName;
                        SizeDat = app.ScatterPlot(n).Children(g).SizeData(index);
                        %plot new highlight
                        if any(index)
                            x = app.ScatterPlot(n).Children(g).XData(index);
                            y = app.ScatterPlot(n).Children(g).YData(index);
                            app.ScatterPlot(n).NextPlot = "add";
                            scatter(app.ScatterPlot(n),x,y,SizeDat,0.5,"red","filled")
                            app.ScatterPlot(1).Children(1).DataTipTemplate.DataTipRows(1).Label="RT:";
                            app.ScatterPlot(1).Children(1).DataTipTemplate.DataTipRows(2).Label="mz:";
                            app.ScatterPlot(1).Children(1).DataTipTemplate.DataTipRows(3)=dataTipTextRow("Feature" , FeatName);
                            app.ScatterPlot(n).Children(1).DataTipTemplate.DataTipRows(4) = [];
                        end
                    end
                end
            end

            % Check if the selected cell contains a table then display
            % content
            if iscell(eventdata.Source.Data{selectedRow,selectedColumn})
                if istable(eventdata.Source.Data{selectedRow,selectedColumn}{:})
                    % Get the table from the cell
                    selectedTable = eventdata.Source.Data{selectedRow,selectedColumn}{:};
                    % Display the data in a new figure or dialog box
                    TableWindow = uifigure("Position",[100,100,920,400],Name="Database results for " + app.FeatureData.NameStringArray(selectedRow));
                    app.ResultSubDisplay = uitable(TableWindow,"Data", selectedTable, 'Position', [5,5,910,390],'UserData',selectedRow);
                    app.ResultSubDisplay.CellSelectionCallback = @(src,event) {SubDisplayCellPlot(app,src,event)};
                end
            end
        end
        function app = SubDisplayCellPlot(app, src, eventdata)
            if isempty(eventdata.Indices) % case when user clicks parts of table without cell
                return
            end
            selectedRow = eventdata.Indices(1);
            selectedColumn = eventdata.Indices(2);
            if selectedColumn == 8 %plot Spectra comparison
                FeatIndex = src.UserData;
                FeatName = app.FeatureData.NameStringArray(FeatIndex);
                Score = src.Data{selectedRow,selectedColumn};
                CompoundName = src.Data{selectedRow,1};

                DbSpectra = app.FeatureData.DataBaseSpectra{FeatIndex,1};
                ScoreAndID = app.FeatureData.DBScoreSpectraID{FeatIndex,1};

                MeasuredSpectra = app.FeatureData.FeatureMSnSpectra{FeatIndex,1};

                mzA = MeasuredSpectra(:,1);
                mzB = DbSpectra(:,1);
                IntA = MeasuredSpectra(:,ScoreAndID(selectedRow,2)+1);
                IntB = DbSpectra(:,ScoreAndID(selectedRow,3)+1);
                %scale
                IntA = IntA./max(IntA);
                IntB = IntB./max(IntB);
                idA = IntA ~= 0;
                idB = IntB ~= 0;

                SpecFig = uifigure("Name","MSn Spectra of "+FeatName);
                SpecAx = uiaxes(SpecFig,"Units","normalized","InnerPosition",[0.1,0.1,0.85,0.85]);
                xlabel(SpecAx,"m/z [Da]")
                ylabel(SpecAx,"normalized Intensity")
                stem(SpecAx,mzA(idA),IntA(idA),Marker="none")
                hold(SpecAx,"on")
                stem(SpecAx,mzB(idB),-IntB(idB),Marker="none")
                hold(SpecAx,"off")
                ylim(SpecAx,[-1.1,1.1])
                legend(SpecAx,["Measured Spectrum","Database Spectrum"],"Location","northwest")
                title(SpecAx,CompoundName + ", Score: "+ Score)
            end
        end
        %% PCA
        function PerformPCA(app)
            %preparation
            %get number of Samples of every group
            numSamps=app.FeatureData.NumberOfFilesArray;
            cumSamps=cumsum(numSamps);
            color=app.colorPalette(1:length(numSamps),:); %default color definition for gscatter
            labels=[app.FeatureData.GroupName]; %get group labels, when augmented groups replace combined name with abstract name
            idx = contains(labels,"+");
            labels(idx) = app.FeatureData.AbstractGroupName(idx);
            replabels=cell(numSamps(1),1); %preallocate then build cell array for gscatter grouping
            repColor=cell(length(numSamps),1);
            for n=1:length(numSamps)
                replabels{1,n}=repmat(labels(n),numSamps(n),1);
                repColor{1,n}=repmat(color(n,:),numSamps(n),1);
            end
            replabels=vertcat(replabels{:}); %unpack cell array
            repColor=vertcat(repColor{:}); %unpack cell array

            %% perform pca
            PCAdata=app.FeatureData.IntensityArray'; %transpose so observations = rows, variables = columns
            [coeff,score,~,~,explained]=pca(PCAdata,"Centered",false);
            % check for meaningful result
            if size(score,2)==1
                uialert(app.MainWindow,["Only one PC calculated"; "Add more Samples or use less restrictive Filters"],'Bad Result','Icon','warning');
                return
            end
            % plot
            % build figure
            PCAres=uifigure("Name","PCA Score Plots");
            app.PCAMultiplot(1)=tiledlayout(PCAres,2,2);
            app.PCAMultiplot(1).TileSpacing = 'compact';
            app.PCAMultiplot(1).Padding = 'compact';
            s1=nexttile(app.PCAMultiplot(1));
            % PC1 vs 2
            gscatter(s1,score(:,1),score(:,2),replabels,color);
            s1.XAxisLocation='Origin';
            s1.YAxisLocation='Origin';
            xlabel(s1,'PC1')
            ylabel(s1,'PC2')
            title(s1,'Scoreplot PC1 vs PC2')
            grid(s1,'on')
            % PC1 vs 3
            s2=nexttile(app.PCAMultiplot(1));
            if size(score,2)>=3
                gscatter(s2,score(:,1),score(:,3),replabels,color);
                s2.XAxisLocation='Origin';
                s2.YAxisLocation='Origin';
                xlabel(s2,'PC1')
                ylabel(s2,'PC3')
                title(s2,'Scoreplot PC1 vs PC3')
                grid(s2,'on')
                % PC2 vs 3
                s3=nexttile(app.PCAMultiplot(1));
                gscatter(s3,score(:,2),score(:,3),replabels,color);
                s3.XAxisLocation='Origin';
                s3.YAxisLocation='Origin';
                xlabel(s3,'PC2')
                ylabel(s3,'PC3')
                title(s3,'Scoreplot PC2 vs PC3')
                grid(s3,'on')
            end
            %Explained Variance
            s4=nexttile(app.PCAMultiplot(1));
            yyaxis(s4,"left")
            bar(s4,explained);
            ylabel(s4,'individual [%]')
            yyaxis(s4,"right")
            plot(s4,cumsum(explained),'LineWidth',2)
            title(s4,'Explained Variance')
            ylabel(s4,'Cumulative [%]')
            xlabel(s4,'Principle Component')
            %3D scatter
            if size(score,2)>=3
                s3D=uifigure("Name","3D Score Plot");
                app.PCA3Dplot=uiaxes(s3D,'Position',[10 10 550 400]);
                scatter3(app.PCA3Dplot,score(1:numSamps(1),1),score(1:numSamps(1),2),score(1:numSamps(1),3),50,repColor(1:numSamps(1),:),'filled')
                hold(app.PCA3Dplot,"on")
                for n=1:length(numSamps)-1
                    scatter3(app.PCA3Dplot,score(cumSamps(n)+1:cumSamps(n+1),1),score(cumSamps(n)+1:cumSamps(n+1),2),score(cumSamps(n)+1:cumSamps(n+1),3),50,repColor(cumSamps(n)+1:cumSamps(n+1),:),'filled')
                end
                hold(app.PCA3Dplot(1),"off")
                app.PCA3Dplot.XAxisLocation='Origin';
                app.PCA3Dplot.YAxisLocation='Origin';
                legend(app.PCA3Dplot,labels,'Interpreter','none')
                title(app.PCA3Dplot,"3D Score Plot")
                grid(app.PCA3Dplot,'on')
            end
            drawnow
            %bi-plots
            %% ask for number of Loadings in Loading plot
            bigload=sum(abs(coeff(:,1:3)),2);
            NumLoadings = numel(bigload);
            prompt = 'Select the number of loadings shown in Biplots:';
            dlgtitle = "Number of Loadings: "+ NumLoadings;
            dims = [1 35];
            definput = {'15'};
            num2Plot = inputdlg(prompt,dlgtitle,dims,definput);
            num2Plot = str2double(num2Plot{:});
            %extract highest loadings

            [~,idx]=maxk(bigload,num2Plot);
            bigcoeff=coeff(idx,:);
            %get Var-names
            loadingNames=app.FeatureData.NameStringArray(idx);
            %create plot
            PCAbi=uifigure("Name","PCA Biplots");
            app.PCAMultiplot(2)=tiledlayout(PCAbi,2,2);
            app.PCAMultiplot(2).TileSpacing = 'compact';
            app.PCAMultiplot(2).Padding = 'compact';
            sb1=nexttile(app.PCAMultiplot(2));
            biplot(sb1,bigcoeff(:,1:2),'Scores',score(:,1:2),'Varlabels',loadingNames,'MarkerSize',15)
            xlabel(sb1,'Component 1')
            ylabel(sb1,'Component 2')
            title(sb1,'Biplot PC1 vs PC2')
            if size(score,2)>=3
                sb2=nexttile(app.PCAMultiplot(2));
                biplot(sb2,[bigcoeff(:,1),bigcoeff(:,3)],'Scores',[score(:,1),score(:,3)],'Varlabels',loadingNames,'MarkerSize',15)
                xlabel(sb2,'Component 1')
                ylabel(sb2,'Component 3')
                title(sb2,'Biplot PC1 vs PC3')
                sb3=nexttile(app.PCAMultiplot(2));
                biplot(sb3,bigcoeff(:,2:3),'Scores',score(:,2:3),'Varlabels',loadingNames,'MarkerSize',15)
                xlabel(sb3,'Component 2')
                ylabel(sb3,'Component 3')
                title(sb3,'Biplot PC2 vs PC3')
                sb4=nexttile(app.PCAMultiplot(2));
                biplot(sb4,bigcoeff(:,1:3),'Scores',score(:,1:3),'Varlabels',loadingNames,'MarkerSize',15)
                title(sb4,'Biplot PC1 vs PC2 vs PC3')
            end
            drawnow
        end


        function RebuildTempFiles(app,previewCells,varargin)
            %assign TemporaryFile names
            for n = 1:numel(app.Data)
                app.Data(n).RawDataFile = tempname +".mat";
                app.Data(n).ROIDataFile = tempname +".mat";
            end
            if isscalar(varargin)
                roiCells = varargin{1};
                for n = 1:width(roiCells)
                    if ~isempty(roiCells{1,n})
                        app.Data(n).ROIDataFileObj = matfile(app.Data(n).ROIDataFile,Writable=true);
                        app.Data(n).ROIDataFileObj.ROIMat = roiCells{1,n};
                        app.Data(n).ROIDataFileObj.ROIMatBLK = roiCells{2,n};
                        app.Data(n).ROIDataFileObj.ROImzVec = roiCells{3,n};
                        app.Data(n).ROIDataFileObj.timeVec = roiCells{4,n};
                    end
                end
            end

            for n = 1:width(app.Data)
                app.Data(n).RawDataFileObj = matfile(app.Data(n).RawDataFile,Writable=true);

                %predefine Variables in .mat file
                app.Data(n).RawDataFileObj.PreviewTICs = previewCells{1,n};
                app.Data(n).RawDataFileObj.PreviewBPCs = previewCells{2,n};
                app.Data(n).RawDataFileObj.PreviewTimes = previewCells{3,n};

                app.Data(n).RawDataFileObj.PeakDataMS1 = {[]};
                app.Data(n).RawDataFileObj.TimeDataMS1 = {[]};

                app.Data(n).RawDataFileObj.PeakDataMSn = {[]};
                app.Data(n).RawDataFileObj.TimeDataMSn = {[]};
                app.Data(n).RawDataFileObj.Precursor = {[]};
                app.Data(n).RawDataFileObj.CollisionEnergy = {[]};
                app.Data(n).RawDataFileObj.CollisionType = {[]};

            end
        end

        function SaveData(app)
            d = uiprogressdlg(app.MainWindow,'Title','Exporting Data','Message','Please Wait','Indeterminate','on');
            exportData.RawData = app.Data;
            %remove temporary file locations and objects
            for n = 1:numel(app.Data)
                exportData.RawData(n).RawDataFile = [];
                exportData.RawData(n).RawDataFileObj = 0;
                exportData.RawData(n).ROIDataFile = [];
                exportData.RawData(n).ROIDataFileObj = 0;
            end
            exportData.FeatureData = app.FeatureData;
            exportData.FullFeatureData = app.FullFeatureData;
            exportData.augmentationAssignment = app.augmentationAssignment;
            exportData.maxP = app.maxP;
            exportData.minFold = app.minFold;
            exportData.mzMergeTol = app.mzMergeTol;
            exportData.mzMergeTolUnit = app.mzMergeTolUnit;
            exportData.rtMergeTol = app.rtMergeTol;
            exportData.dataBaseFile = app.dataBaseFile;
            previews = cell(3,numel(app.Data));
            for n = 1:numel(app.Data)
                previews{1,n} = app.Data(n).RawDataFileObj.PreviewTICs;
                previews{2,n} = app.Data(n).RawDataFileObj.PreviewBPCs;
                previews{3,n} = app.Data(n).RawDataFileObj.PreviewTimes;
            end
            exportData.previews = previews;
            roiResults = cell(4,numel(app.Data));
            for n = 1:numel(app.Data)
                if exist(app.Data(n).ROIDataFile,"file")
                    roiResults{1,n} = app.Data(n).ROIDataFileObj.ROIMat;
                    roiResults{2,n} = app.Data(n).ROIDataFileObj.ROIMatBLK;
                    roiResults{3,n} = app.Data(n).ROIDataFileObj.ROImzVec;
                    roiResults{4,n} = app.Data(n).ROIDataFileObj.timeVec;
                end
            end
            exportData.roiResults = roiResults;
            save(app.saveLocation,'exportData');
            message={'Poject saved at:', convertStringsToChars(app.saveLocation)};
            close(d)
            uialert(app.MainWindow,message,"File saved successfully","Icon","success");
        end
    end
    %% Public Callbacks Block
    methods (Access = public)
        %% Tab Options
        function app = GroupNameChange(app,~,event,CurrentTab)
            app.Tab(CurrentTab).Title = event.Value;
            app.Data(CurrentTab).GroupName = event.Value;
        end

        function app = EvaluateMSn(app)
            %load MSn Data
            d = uiprogressdlg(app.MainWindow,'Title','Please Wait','Message','Preparing');
            ValPerGroup = 1/(app.groupCounter+2);
            for nGroup = 1:app.groupCounter
                %check for MSn Data
                d.Message = "Loading MS2 for Group " + app.Data(nGroup).GroupName;
                app.Data(nGroup) = app.Data(nGroup).ReadData(app.Data(nGroup).Files,2);
                d.Value = d.Value+ValPerGroup;
            end
            d.Message = "Assigning MS2 Spectra to Features";
            %assign Spectra to feature
            app.FeatureData = app.FeatureData.AssignSpectra2Features(app.Data);
            d.Value = d.Value+ValPerGroup;
            %between Groups
            d.Message = "Processing assigned MSn spectra";
            app.FeatureData = app.FeatureData.ProcessGroupMSnSpectra;
            app.FeatureData = app.FeatureData.RefGroupScaling(app.augmentationAssignment);
            app.FeatureData = app.FeatureData.CalculateSignificance(app.hochbergFilterFlag,app.falseDiscoveryRate);
            d.Value = d.Value+ValPerGroup;
        end

        function app = SelectFiles(app,~,~,CurrentTab)
            text = "Select Files for " + app.Data(CurrentTab).GroupName;
            [FileName,Path] = uigetfile({'*.mzXML;*.mzML','MS Files (*.mzXML,*.mzML)'},text,'MultiSelect','on');
            if ischar(Path) == 0 %Check Cancel input
                figure(app.Tab(CurrentTab).Parent.Parent)
                uialert(app.Tab(CurrentTab).Parent.Parent,"Task aborted by user","No Data Selected","Icon","warning")
                return
            else
                figure(app.Tab(CurrentTab).Parent.Parent)
                app.Data(CurrentTab).FileNames = FileName;
                app.Data(CurrentTab).Files=fullfile(Path,FileName);
                if isempty(app.Data(CurrentTab).Files) == false
                    % delete previous data

                    delete(app.Data(CurrentTab).RawDataFile)
                    % rebuild file
                    app.Data(CurrentTab).RawDataFileObj = matfile(app.Data(CurrentTab).RawDataFile,Writable=true);
                    %predefine Variables in .mat file
                    app.Data(CurrentTab).RawDataFileObj.PreviewTICs = {[]};
                    app.Data(CurrentTab).RawDataFileObj.PreviewBPCs = {[]};
                    app.Data(CurrentTab).RawDataFileObj.PreviewTimes = {[]};

                    app.Data(CurrentTab).RawDataFileObj.PeakDataMS1 = {[]};
                    app.Data(CurrentTab).RawDataFileObj.TimeDataMS1 = {[]};

                    app.Data(CurrentTab).RawDataFileObj.PeakDataMSn = {[]};
                    app.Data(CurrentTab).RawDataFileObj.TimeDataMSn = {[]};
                    app.Data(CurrentTab).RawDataFileObj.Precursor = {[]};
                    app.Data(CurrentTab).RawDataFileObj.CollisionEnergy = {[]};
                    app.Data(CurrentTab).RawDataFileObj.CollisionType = {[]};
                    %remove FileInfo Labels
                    if isgraphics(app.InfoLabels(1,CurrentTab))
                        delete(app.InfoLabels(:,CurrentTab))
                        app.InfoLabels(:,CurrentTab) = gobjects(4,1);
                    end
                end
            end
            if app.Data(CurrentTab).BLKSubtraction == true
                text = "Select Blank Files for " + app.Data(CurrentTab).GroupName;
                [BLKFileName,BLKPath] = uigetfile({'*.mzXML;*.mzML','MS Files (*.mzXML,*.mzML)'},text,'MultiSelect','on');
                if ischar(BLKPath) == 0 %Check Cancel input
                    figure(app.Tab(CurrentTab).Parent.Parent)
                    uialert(app.Tab(CurrentTab).Parent.Parent,"Task aborted by user","No Data Selected","Icon","warning")
                    return
                else
                    figure(app.Tab(CurrentTab).Parent.Parent)
                    app.Data(CurrentTab).BlankFiles=fullfile(BLKPath,BLKFileName);
                end
            end
            d = uiprogressdlg(app.MainWindow,'Title','Checking Files','Message','Please Wait','Indeterminate','on');

            % delete old Plot
            if app.currentPlot(1,CurrentTab) == true
                delete(app.PreviewAxes(CurrentTab).Children)
                delete(app.PreviewAxes(CurrentTab))
                app.currentPlot(1,CurrentTab)=false;
            elseif app.currentPlot(2,CurrentTab) == true
                delete(app.PreviewTiled(CurrentTab).Children)
                delete(app.PreviewTiled(CurrentTab))
                app.currentPlot(2,CurrentTab)=false;
            end

            app.Data(CurrentTab) = app.Data(CurrentTab).DataCheck;
            nScansMS1=round(mean([app.Data(CurrentTab).DataInfo.NumberOfScansMS1]));
            nScansMSn=sum([app.Data(CurrentTab).DataInfo.NumberOfScansMSn]~=0);
            freq=round(mean([app.Data(CurrentTab).DataInfo.ScanFrequenceMS1]),1);
            app.InfoLabels(1,CurrentTab) = uilabel(app.Tab(CurrentTab),"Text","File Infos:",...
                "Position",[130,620,200,20],...
                "HorizontalAlignment","left",...
                "VerticalAlignment","center",...
                "Tooltip",["Selected Files:";app.Data(CurrentTab).Files;app.Data(CurrentTab).BlankFiles]);
            app.InfoLabels(2,CurrentTab) = uilabel(app.Tab(CurrentTab),"Text","Scan Rate MS1: " + freq + " Hz",...
                "Position",[130,600,200,20],...
                "HorizontalAlignment","left",...
                "VerticalAlignment","center");
            app.InfoLabels(3,CurrentTab) = uilabel(app.Tab(CurrentTab),"Text","Number of MS1 Scans: " + nScansMS1,...
                "Position",[130,580,200,20],...
                "HorizontalAlignment","left",...
                "VerticalAlignment","center",...
                "Tooltip","Average number of MS1 scans");
            app.InfoLabels(4,CurrentTab) = uilabel(app.Tab(CurrentTab),"Text","Files with MS/MS Scans: " + nScansMSn +"/"+numel(app.Data(CurrentTab).Files),...
                "Position",[130,560,200,20],...
                "HorizontalAlignment","left",...
                "VerticalAlignment","center",...
                "Tooltip","Number of files with MS/MS data");
            app.ROIOptionsPanel(CurrentTab).StartTimeField.Limits = [app.Data(CurrentTab).Start,app.Data(CurrentTab).End];
            app.ROIOptionsPanel(CurrentTab).EndTimeField.Limits = [app.Data(CurrentTab).Start,app.Data(CurrentTab).End];
            app.ROIOptionsPanel(CurrentTab).StartTimeField.Value = app.Data(CurrentTab).Start;
            app.ROIOptionsPanel(CurrentTab).EndTimeField.Value = app.Data(CurrentTab).End;
            %ask for File Preview
            app.PreviewButton(CurrentTab).Visible = "on";
            app.EICButton(CurrentTab).Visible = "on";
            close(d)
            answer = uiconfirm(app.MainWindow,'Preview files?','Preview','Icon','question','Options',{'Yes','No'});
            switch answer
                case 'Yes'
                    PreviewFile(app,CurrentTab)
            end
            %activate panels
            addIntegrationOptions(app,CurrentTab)
            app.MainOptionsPanel(CurrentTab).Container.Enable = true;
            app.ROIOptionsPanel(CurrentTab).Container.Enable = true;
            app.IntegrationOptionsPanel(CurrentTab).Container.Enable = true;
            app.PeakAlignPanel(CurrentTab).Container.Enable = app.Data(CurrentTab).Peakalign;
            app.MSAlignPanel(CurrentTab).Container.Enable = app.Data(CurrentTab).MSalign;
            app.BaselinePanel(CurrentTab).Container.Enable = app.Data(CurrentTab).BaseCorr;
            app.GolayPanel(CurrentTab).Container.Enable = app.Data(CurrentTab).Smoothing;
            app.ScalingPanel(CurrentTab).Container.Enable = app.Data(CurrentTab).ScalingCorr;
            app.ISPanel(CurrentTab).Container.Enable = app.Data(CurrentTab).ISTDCorr;
            app.AddNLossPanel(CurrentTab).Container.Enable = app.Data(CurrentTab).AdductFilter;

            %expand SampleScaling Vector and remove old values
            app.Data(CurrentTab).SampScale = ones(size(app.Data(CurrentTab).FileNames,1),1);

            % Warn User by Switching Lamps to Red
            if isempty(app.ScalingPanel(CurrentTab).window) == false
                app.ScalingPanel(CurrentTab).ScalingLamp.Color = [1,0,0];
            end
            app.AddNLossPanel(CurrentTab).AddNLossLamp.Color = [1,0,0];
            CheckReady(app)
            close(d)
        end

        function app = TestParameters(app,~,~,CurrentTab)
            %check if Preview already loaded
            d = uiprogressdlg(app.MainWindow,'Title','Preparing','Message','Please Wait','Indeterminate','on');
            check = app.Data(CurrentTab).RawDataFileObj.PeakDataMS1;
            if height(check) ~= height(app.Data(CurrentTab).Files)
                d.Title = 'Loading Data';
                app.Data(CurrentTab)=app.Data(CurrentTab).ReadData(app.Data(CurrentTab).Files,1);
            end
            d.Title = 'Processing Data';
            %build TempDataFile
            app.Data(CurrentTab).TempDataFile = tempname +".mat";
            app.Data(CurrentTab).TempDataFileObj = matfile(app.Data(CurrentTab).TempDataFile,Writable=true);
            %predefine Variables in .mat file
            app.Data(CurrentTab).TempDataFileObj.ROICells  = {[]};
            app.Data(CurrentTab).TempDataFileObj.TimeCells  = {[]};
            app.Data(CurrentTab).TempDataFileObj.ROIMat = [];
            app.Data(CurrentTab).TempDataFileObj.ROIMatBLK  = [];
            app.Data(CurrentTab).TempDataFileObj.ROImzVec = [];
            app.Data(CurrentTab).TempDataFileObj.timeVec  = [];

            app.Data(CurrentTab) = app.Data(CurrentTab).CutScansToSize;
            %Apply MSAlign
            if app.Data(CurrentTab).MSalign == true
                d.Message = 'Performing Spectral Alignment';
                app.Data(CurrentTab) = app.Data(CurrentTab).AlignScans("preview");
            end
            d.Value = .2;
            %ROISearch
            d.Message = 'Searching for ROIs';
            app.Data(CurrentTab) = app.Data(CurrentTab).AutoROI("preview");
            d.Value = .4;
            %Apply Baseline Correction
            if app.Data(CurrentTab).BaseCorr == true
                d.Message = 'Performing Baseline Correction';
                app.Data(CurrentTab) = app.Data(CurrentTab).CorrectBaseline("preview");
                d.Value = .5;
            end
            %Apply Smoothing
            if app.Data(CurrentTab).Smoothing == true
                d.Message = 'Performing Golay Smoothing';
                app.Data(CurrentTab) = app.Data(CurrentTab).SmoothPeaks("preview");
                d.Value = .6;
            end
            %Apply Contaminant Filter
            if app.Data(CurrentTab).ContaminantFilter == true
                d.Message = 'Filtering Contaminants';
                pause(0.5)
                app.Data(CurrentTab) = app.Data(CurrentTab).removeContaminants;
            end
            d.Value = .9;
            d.Message = 'Plotting Graphs';
            %Graphs
            MSroi = app.Data(CurrentTab).TempDataFileObj.ROICells(1,1);
            MSroi = MSroi{:};
            times = app.Data(CurrentTab).TempDataFileObj.TimeCells(1,1);
            times = times{:};
            mzroi=flip(app.Data(CurrentTab).TempDataFileObj.ROImzVec);
            mzInt=max(MSroi);
            mzInt=rescale(mzInt,1,100);
            % Remove and Rebuild Containers
            % delete old Plot
            if app.currentPlot(1,CurrentTab) == true
                delete(app.PreviewAxes(CurrentTab).Children)
                delete(app.PreviewAxes(CurrentTab))
                app.currentPlot(1,CurrentTab)=false;
            elseif app.currentPlot(2,CurrentTab) == true
                delete(app.PreviewTiled(CurrentTab).Children)
                delete(app.PreviewTiled(CurrentTab))
                app.currentPlot(2,CurrentTab)=false;
            end
            app.PreviewTiled(CurrentTab) = tiledlayout(app.Tab(CurrentTab),2,1,"TileSpacing","compact",Padding="compact",Units="pixels",OuterPosition=[470 200 680 420],PositionConstraint="outerposition",Visible="off");
            app.PreviewChrom(CurrentTab) = nexttile(app.PreviewTiled(CurrentTab));
            colororder(app.PreviewChrom(CurrentTab),"gem12")
            % Plot ROIs
            Tag="ROIs for " + app.Data(CurrentTab).GroupName;
            plot(app.PreviewChrom(CurrentTab),times,MSroi)
            for n=size(app.PreviewChrom(CurrentTab).Children,1):-1:1
                app.PreviewChrom(CurrentTab).Children(n).DataTipTemplate.DataTipRows(1).Label="RT:";
                app.PreviewChrom(CurrentTab).Children(n).DataTipTemplate.DataTipRows(2).Label="Intensity:";
                app.PreviewChrom(CurrentTab).Children(n).DataTipTemplate.DataTipRows(3) = dataTipTextRow("ROI m/z:",repmat(mzroi(n),size(times,1),1),'%.4g');
            end
            xlabel(app.PreviewChrom(CurrentTab),"Time [sec]")
            ylabel(app.PreviewChrom(CurrentTab),"Intensity [arb]")
            title(app.PreviewChrom(CurrentTab), Tag)
            %Plot MZ values
            app.PreviewMZ(CurrentTab)=nexttile(app.PreviewTiled(CurrentTab));
            Tag="ROI mz values for " + app.Data(CurrentTab).GroupName;
            mzroi=flip(mzroi);
            stem(app.PreviewMZ(CurrentTab),mzroi,mzInt,'Marker','none','Color','k')
            app.PreviewMZ(CurrentTab).Children(1).DataTipTemplate.DataTipRows(1).Label="m/z:";
            app.PreviewMZ(CurrentTab).Children(1).DataTipTemplate.DataTipRows(2).Label="Relative intensity:";
            ylim(app.PreviewMZ(CurrentTab),[0,110])
            xlabel(app.PreviewMZ(CurrentTab),"m/z [Da]")
            ylabel(app.PreviewMZ(CurrentTab),"Relative Intensity [%]")
            title(app.PreviewMZ(CurrentTab), Tag)
            delete(app.PreviewAxes(CurrentTab))
            app.PreviewTiled(CurrentTab).Visible = "on";
            app.currentPlot(2,CurrentTab) = true;
            d.Message = 'Done';
            d.Value = 1;
            delete(app.Data(CurrentTab).TempDataFile)
            app.Data(CurrentTab).TempDataFile = "";
            pause(0.5)
            close(d) %Close loading box
        end
        function app = PlotEIC(app,~,~,CurrentTab)
            d = uiprogressdlg(app.MainWindow,'Title','Please Wait','Message','Select EICs','Indeterminate','on');
            % construct input window and get values
            [masses,tolerance,tolUnit] = app.EICSelectionGUI(CurrentTab);
            %check for user abort
            if isempty(masses)
                uialert(app.Tab(CurrentTab).Parent.Parent,"Task aborted by user","No masses selected","Icon","warning")
                return
            end

            %Full load Data
            %check if files already loaded
            check = app.Data(CurrentTab).RawDataFileObj.PeakDataMS1;
            if height(check) ~= height(app.Data(CurrentTab).Files) || isempty(check{1,1})
                d.Message = "Loading Files";
                app.Data(CurrentTab) = app.Data(CurrentTab).ReadData(app.Data(CurrentTab).Files,1);
            end

            %extract EICs
            d.Message = 'Building EICs';
            peaks = app.Data(CurrentTab).RawDataFileObj.PeakDataMS1;
            times = app.Data(CurrentTab).RawDataFileObj.TimeDataMS1;
            EICStorage = cell(size(app.Data(CurrentTab).Files,1),numel(masses));
            for n = 1:numel(masses)
                mass = masses(n);
                parfor p = 1:size(peaks,1)
                    EICStorage{p,n} = buildEIC(peaks{p,1},mass,tolerance,tolUnit);
                end
            end
            %plot
            fig = figure("Name","EICs for "+app.Data(CurrentTab).GroupName,"NumberTitle","off");
            t = tiledlayout("vertical");
            colororder(fig,"gem12")
            for n = 1:size(EICStorage,2)
                nexttile(t)
                hold on
                for p = 1:size(EICStorage,1)
                    plot(times{p,1},EICStorage{p,n})
                    t.Children(1).Children(1).DataTipTemplate.DataTipRows(1).Label="RT:";
                    t.Children(1).Children(1).DataTipTemplate.DataTipRows(2).Label="Intensity:";
                end
                hold off
                title("EIC of m/z " + masses(n) + " Da " + "\pm " + tolerance + " " + tolUnit)
                xlabel("time [s]")
                ylabel("Intensity [arb]")
                legend(app.Data(CurrentTab).FileNames,Interpreter="none")
            end
            drawnow
            close(d)
        end

        %% Main Options
        function app = FileTypeChange(app,~,event,CurrentTab)
            %PolarityEvent stores changed MS Polarity in calling Tab
            app.Data(CurrentTab).MSFileType = event.Value;
        end
        function app = BlankChange(app,~,event,CurrentTab)
            %stores changed Blank Subtraction value in calling Tab
            app.Data(CurrentTab).BLKSubtraction = event.Value;
            if event.Value == true && isempty(app.Data(CurrentTab).BlankFiles)==true
                text = "Select Blank Files for " + app.Data(CurrentTab).GroupName;
                [BLKFileName,BLKPath] = uigetfile({'*.mzXML;*.mzML','MS Files (*.mzXML,*.mzML)'},text,'MultiSelect','on');
                if ischar(BLKPath) == 0 %Check Cancel input
                    figure(app.Tab(CurrentTab).Parent.Parent)
                    uialert(app.Tab(CurrentTab).Parent.Parent,"Task aborted by user","No Data Selected","Icon","warning")
                    app.Data(CurrentTab).BLKSubtraction = false;
                    app.MainOptionsPanel(CurrentTab).BlankTickBox.Value = false;
                    return
                else
                    figure(app.Tab(CurrentTab).Parent.Parent)
                    app.Data(CurrentTab).BlankFiles=fullfile(BLKPath,BLKFileName);
                end
            end
            if app.Data(CurrentTab).ISTDCorr == true
                app.ISPanel(CurrentTab).ApplyDrop.Enable = event.Value;
                app.ISPanel(CurrentTab).ApplyLabel.Enable = event.Value;
                app.ISPanel(CurrentTab).OrderLabel.Enable = event.Value;
                app.ISPanel(CurrentTab).OrderDrop.Enable = event.Value;
            end
            CheckReady(app)
        end
        function app = PeakAlignmentChange(app,~,event,CurrentTab)
            %stores changed Peak alignment value in calling Tab and calls PeakAlignmentPanel
            app.Data(CurrentTab).Peakalign = event.Value;
            %toggle Panel Visibility
            app.PeakAlignPanel(CurrentTab).Container.Enable = event.Value;

        end
        function app = MSAlignmentChange(app,~,event,CurrentTab)
            %stores changed MS Alignment value in calling Tab and calls MSAlignmentPanel
            app.Data(CurrentTab).MSalign = event.Value;
            %toggle Panel Visibility
            app.MSAlignPanel(CurrentTab).Container.Enable = event.Value;

        end
        function app = BaseCorrChange(app,~,event,CurrentTab)
            %stores changed BaselineCorrection value in calling Tab and calls BaseCorrPanel
            app.Data(CurrentTab).BaseCorr = event.Value;
            %toggle Panel Visibility
            app.BaselinePanel(CurrentTab).Container.Enable = event.Value;
        end
        function app = GolayChange(app,~,event,CurrentTab)
            %stores changed Smoothing value in calling Tab and calls GolayPanel
            app.Data(CurrentTab).Smoothing = event.Value;
            %toggle Panel Visibility
            app.GolayPanel(CurrentTab).Container.Enable = event.Value;

        end
        function app = ISTDChange(app,~,event,CurrentTab)
            %stores changed ISTDCorr value in calling Tab and calls ISTDPanel
            app.Data(CurrentTab).ISTDCorr = event.Value;
            %toggle Panel Visibility
            app.ISPanel(CurrentTab).Container.Enable = event.Value;
            if app.Data(CurrentTab).BLKSubtraction == true
                app.ISPanel(CurrentTab).ApplyDrop.Enable = event.Value;
                app.ISPanel(CurrentTab).ApplyLabel.Enable = event.Value;
                app.ISPanel(CurrentTab).OrderLabel.Enable = event.Value;
                app.ISPanel(CurrentTab).OrderDrop.Enable = event.Value;
            end
            CheckReady(app)
        end
        function app = IsoChange(app,~,event,CurrentTab)
            %stores changed IsotopeFilter value in calling Tab and calls IsotopePanel
            app.Data(CurrentTab).IsotopeFilter = event.Value;
        end
        function app = ScaleChange(app,~,event,CurrentTab)
            %stores changed AddNLoss value in calling Tab and calls AddNlossPanel
            app.Data(CurrentTab).ScalingCorr = event.Value;
            %toggle Panel Visibility
            app.ScalingPanel(CurrentTab).Container.Enable = event.Value;
            CheckReady(app)
        end
        function app = AddNLossChange(app,~,event,CurrentTab)
            %stores changed AddNLoss value in calling Tab and calls AddNlossPanel
            app.Data(CurrentTab).AdductFilter = event.Value;
            %toggle Panel Visibility
            app.AddNLossPanel(CurrentTab).Container.Enable = event.Value;
            CheckReady(app)
        end
        function app = ContaminantChange(app,~,event,CurrentTab)
            %stores changed Contaminant value in calling Tab and calls ContaminantPanel
            app.Data(CurrentTab).ContaminantFilter = event.Value;
        end
        function app = mzTolChange(app,~,event,CurrentTab)
            %stores changed Contaminant value in calling Tab and calls ContaminantPanel
            app.Data(CurrentTab).mzTol = event.Value;
        end
        function app = mzTolUnitChange(app,~,event,CurrentTab)
            app.Data(CurrentTab).mzTolUnit = event.Value;
        end
        function app = RTTolChange(app,~,event,CurrentTab)
            %stores changed Contaminant value in calling Tab and calls ContaminantPanel
            app.Data(CurrentTab).RTTol = event.Value;
        end
        %% ROI Panel
        function app = ThreshChange(app,~,event,CurrentTab)
            app.Data(CurrentTab).thresh = event.Value;
        end
        function app = mzerrorChange(app,~,event,CurrentTab)
            app.Data(CurrentTab).mzerror = event.Value;
        end
        function app = mzerrorUnitChange(app,~,event,CurrentTab)
            app.Data(CurrentTab).mzErrorUnit = event.Value;
        end
        function app = minROIChange(app,~,event,CurrentTab)
            app.Data(CurrentTab).minroi = event.Value;
        end
        function app = STimeChange(app,~,event,CurrentTab)
            app.ROIOptionsPanel(CurrentTab).EndTimeField.Limits = [event.Value+0.1,Inf];
            app.Data(CurrentTab).Start = event.Value;
        end
        function app = ETimeChange(app,~,event,CurrentTab)
            app.ROIOptionsPanel(CurrentTab).StartTimeField.Limits = [0,event.Value-0.1];
            app.Data(CurrentTab).End = event.Value;
        end
        %% Integration Panel
        function app = EntropyChange(app,~,event,CurrentTab)
            app.Data(CurrentTab).entropyFilter = event.Value;
            app.IntegrationOptionsPanel(CurrentTab).EntropyDrop.Enable = event.Value;
        end
        function app = EntropyStrengthChange(app,~,event,CurrentTab)
            app.Data(CurrentTab).entropyStrength = event.Value;
        end
        function app = OccurrenceChange(app,~,event,CurrentTab)
            app.Data(CurrentTab).minOccurence = event.Value/100;
        end
        function app = minSNChange(app,~,event,CurrentTab)
            app.Data(CurrentTab).minSignalNoise = event.Value;
        end
        function app = maxWidthChange(app,~,event,CurrentTab)
            app.Data(CurrentTab).maxWidth = event.Value;
        end
        function app = minWidthChange(app,~,event,CurrentTab)
            app.Data(CurrentTab).minWidth = event.Value;
        end
        function app = EvalParamChange(app,~,event,CurrentTab)
            app.Data(CurrentTab).EvaluationParameter = event.Value;
        end
        %% SubPanel Callbacks
        %% Golay
        function app = FrameSizeChanged(app,~,event,CurrentTab)
            app.Data(CurrentTab).FrameSize = event.Value;
            app.GolayPanel(CurrentTab).Degree.Limits = [1,event.Value - 1];
        end
        function app = DegreeChanged(app,~,event,CurrentTab)
            app.Data(CurrentTab).Degree = event.Value;
            app.GolayPanel(CurrentTab).FrameSize.Limits = [event.Value + 1,Inf];
        end
        %% MSAlign
        function app = QuantilChange(app,~,event,CurrentTab)
            app.Data(CurrentTab).mzQuantil = event.Value;
        end
        function app = EstimChange(app,~,event,CurrentTab)
            app.Data(CurrentTab).mzEstimMethod = event.Value;
        end
        function app = CorrChange(app,~,event,CurrentTab)
            app.Data(CurrentTab).mzCorrectionMethod = event.Value;
        end
        %% PeakAlign
        function app = MaxShiftPosChange(app,~,event,CurrentTab)
            app.Data(CurrentTab).maxshiftpos = event.Value;
        end
        function app = MaxShiftNegChange(app,~,event,CurrentTab)
            app.Data(CurrentTab).maxshiftneg = event.Value*-1;
        end
        function app = PulseWidthChange(app,~,event,CurrentTab)
            app.Data(CurrentTab).PulseWidth= event.Value;
        end
        function app = WindowSizeRatioChange(app,~,event,CurrentTab)
            app.Data(CurrentTab).WindowSizeRatio = event.Value;
        end
        function app = SearchSpaceChange(app,~,event,CurrentTab)
            app.Data(CurrentTab).SearchSpace = event.Value;
        end
        function app = IterationChange(app,~,event,CurrentTab)
            app.Data(CurrentTab).Iterations = event.Value;
        end
        function app = GridStepChange(app,~,event,CurrentTab)
            app.Data(CurrentTab).GridSteps = event.Value;
        end
        %% BaseCorr
        function app = WindowSizeChange(app,~,event,CurrentTab)
            app.Data(CurrentTab).WindowSize = event.Value;
        end
        function app = StepSizeChange(app,~,event,CurrentTab)
            app.Data(CurrentTab).StepSize= event.Value;
        end
        function app = RegressionChange(app,~,event,CurrentTab)
            app.Data(CurrentTab).RegressionMethod= event.Value;
        end
        function app = EstimationChange(app,~,event,CurrentTab)
            app.Data(CurrentTab).EstimationMethod = event.Value;
        end
        function app = SmoothChange(app,~,event,CurrentTab)
            app.Data(CurrentTab).SmoothMethod = event.Value;
        end
        function app = QuantilValChange(app,~,event,CurrentTab)
            app.Data(CurrentTab).QuantilVal = event.Value;
        end
        %% ISTD
        function app = ConstructISFigure(app,~,~,CurrentTab)
            app.ISPanel(CurrentTab).window = uifigure("WindowStyle","normal",...
                "Name","Define ISTDs",...
                "Position",[100,100,640,480],...
                "NumberTitle","off");
            app.ISPanel(CurrentTab).InputTable = uitable(app.ISPanel(CurrentTab).window,...
                "ColumnEditable",[false, true, true, true],...
                ColumnName=["Exact mass [Da]","Expected RT [sec]","Accepted Drift [sec]"],...
                ColumnWidth="auto",...
                ColumnFormat={'numeric','numeric','numeric'},...
                Position=[20,22,600,380]);
            app.ISPanel(CurrentTab).Labels(4) = uilabel(app.ISPanel(CurrentTab).window,...
                "Text","Define Internal Standards",...
                FontSize=24,...
                FontWeight="bold",...
                Position=[20,420,300,50]);

            if isempty(app.ISPanel(CurrentTab).oldDat)==false
                [r,~]=size(app.ISPanel(CurrentTab).oldDat);
                if r < app.ISPanel(CurrentTab).NumberSelect.Value %is added -> append new rows
                    newVals = app.ISPanel(CurrentTab).oldDat;
                    newVals(app.ISPanel(CurrentTab).NumberSelect.Value,3) = 0;
                else
                    newVals = app.ISPanel(CurrentTab).oldDat(1:app.ISPanel(CurrentTab).NumberSelect.Value,:);
                end
                app.ISPanel(CurrentTab).InputTable.Data = newVals;
            else
                dat=zeros(app.ISPanel(CurrentTab).NumberSelect.Value,3,'double');
                app.ISPanel(CurrentTab).InputTable.Data = dat;
            end
            app.ISPanel(CurrentTab).OKButton = uibutton(app.ISPanel(CurrentTab).window,"push",...
                "Position",[475,420,155,40],...
                Text="OK");
            app.ISPanel(CurrentTab).OKButton.ButtonPushedFcn = @(src,event){OutputIS(app,src,event,CurrentTab)};
        end

        function app = OutputIS(app,~,~,CurrentTab)
            ISData = app.ISPanel(CurrentTab).InputTable.Data;
            %%Check Correct Inputs
            %Check missing MZ
            if any(ISData(:,1)==0) == true || any(isnan(ISData(:,1))) == true
                uialert(app.ISPanel(CurrentTab).window,'Missing Internal Standard M/Z','Invalid Input');
                %Check missing Expected RT
            elseif any(ISData(:,2)==0) == true || any(isnan(ISData(:,2))) == true
                uialert(app.ISPanel(CurrentTab).window,'Missing Expected Retention Time','Invalid Input');
                %Check missing Accepted Drift
            elseif any(ISData(:,3)==0) == true || any(isnan(ISData(:,3))) == true
                uialert(app.ISPanel(CurrentTab).window,'Missing Accepted Drift','Invalid Input');
            else
                app = ISCloseRequest(app,CurrentTab,ISData);
            end
        end
        function app = ISCloseRequest(app,CurrentTab,ISData)
            app.Data(CurrentTab).ISDat = ISData;
            app.ISPanel(CurrentTab).oldDat = ISData;
            app.ISPanel(CurrentTab).StatusLamp.Color = [0.39,0.83,0.07];
            app.Data(CurrentTab).numISTD=app.ISPanel(CurrentTab).NumberSelect.Value;
            delete(app.ISPanel(CurrentTab).window)
            CheckReady(app)
        end
        function app = ISLampAction(app,~,event,CurrentTab)
            oldVal = app.Data(CurrentTab).numISTD;
            if event.Value == oldVal
                app.ISPanel(CurrentTab).StatusLamp.Color = [0.39,0.83,0.07];
                CheckReady(app)
            else
                app.ISPanel(CurrentTab).StatusLamp.Color = [1.0,0.0,0.0];
                app.ProcessFilesButton.Enable = "off";
            end
        end
        function app = MassCalChange(app,~,event,CurrentTab)
            app.Data(CurrentTab).MassCal = event.Value;
        end
        function app = ISApplyChange(app,~,event,CurrentTab)
            app.Data(CurrentTab).ISApply = event.Value;
        end
        function app = ISOrderChange(app,~,event,CurrentTab)
            app.Data(CurrentTab).ISOrder = event.Value;
        end
        %% Scaling
        function app = ConstructScalingFigure(app,~,~,CurrentTab)
            % check if Files are selected
            if isempty(app.Data(CurrentTab).FileNames)==true
                uialert(app.MainWindow,"Select Files to Continue","No Files Selected","Icon","error")
                return
            else
                app.ScalingPanel(CurrentTab).window = uifigure("WindowStyle","normal",...
                    "Name","Define Scaling Factors",...
                    "Position",[100,100,640,480],...
                    "NumberTitle","off");
                app.ScalingPanel(CurrentTab).InputTable = uitable(app.ScalingPanel(CurrentTab).window,...
                    "ColumnEditable",[false, true],...
                    ColumnName=["Sample File","Scaing Factor"],...
                    ColumnWidth="auto",...
                    ColumnFormat={'char','numeric'},...
                    Position=[20,20,600,360]);
                app.ScalingPanel(CurrentTab).Labels(1) = uilabel(app.ScalingPanel(CurrentTab).window,...
                    "Text","Define Scaling Factors",...
                    FontSize=24,...
                    FontWeight="bold",...
                    Position=[20,420,300,50]);
                app.ScalingPanel(CurrentTab).GroupValField = uieditfield(app.ScalingPanel(CurrentTab).window,"numeric",...
                    "Position",[150,400,90,20],...
                    "Limits",[0,Inf], ...
                    "ValueDisplayFormat","%11.4g",...
                    "Value",app.Data(CurrentTab).GroupScale, ...
                    "RoundFractionalValues","off");
                app.ScalingPanel(CurrentTab).Labels(2) = uilabel(app.ScalingPanel(CurrentTab).window,...
                    "Text","Group wide Factor",...
                    FontSize=12,...
                    Position=[20,400,110,20]);

                app.ScalingPanel(CurrentTab).InputTable.Data = [app.Data(CurrentTab).FileNames,app.Data(CurrentTab).SampScale];

                app.ScalingPanel(CurrentTab).OKButton = uibutton(app.ScalingPanel(CurrentTab).window,"push",...
                    "Position",[475,420,155,40],...
                    Text="OK");
                % Callbacks
                app.ScalingPanel(CurrentTab).OKButton.ButtonPushedFcn = @(src,event){OutputScaling(app,src,event,CurrentTab)};
                app.ScalingPanel(CurrentTab).GroupValField.ValueChangedFcn = @(src,event) {GroupScaleChange(app,src,event,CurrentTab)};
                % Show Window after construction
                app.ScalingPanel(CurrentTab).window.Visible=true;
            end
        end
        function app = OutputScaling(app,~,~,CurrentTab)
            ScaleData = app.ScalingPanel(CurrentTab).InputTable.Data(:,2);
            ScaleData = str2double(ScaleData);
            %Check Correct Inputs
            if ScaleData <= 0
                uialert(app.ScalingPanel(CurrentTab).window,'Scaling factors must not be negative or 0','Invalid Input');
            else
                app = ScaleCloseRequest(app,CurrentTab,ScaleData);
            end
        end
        function app = ScaleCloseRequest(app,CurrentTab,ScaleData)
            app.Data(CurrentTab).SampScale = ScaleData;
            app.ScalingPanel(CurrentTab).ScalingLamp.Color = [0.39,0.83,0.07];
            delete(app.ScalingPanel(CurrentTab).window)
            CheckReady(app)
        end
        function app = GroupScaleChange(app,~,event,CurrentTab)
            %   Detailed explanation goes here
            app.Data(CurrentTab).GroupScale = event.Value;
        end
        %% AddNLoss
        function app = ConstructAddNLossFigure(app,~,~,CurrentTab)

            polarity = app.Data(CurrentTab).RawDataFileObj.polarity;
            polarity = vertcat(polarity{:});

            test = strcmp(polarity,"+");
            if all(test)
                polarity = "positive";
            elseif all(~test)
                polarity = "negative";
            else
                polarity = "both";
            end
            switch polarity
                case "positive"
                    app.AddNLossPanel(CurrentTab).window = PosAdducts(app.Data(CurrentTab));
                case "negative"
                    app.AddNLossPanel(CurrentTab).window = NegAdducts(app.Data(CurrentTab));
                case "both"
                    app.AddNLossPanel(CurrentTab).window = AllAdducts(app.Data(CurrentTab));
            end
            app.AddNLossPanel(CurrentTab).window.AcceptButton.ButtonPushedFcn = @(src,event){OutputAddDat(app,src,event,CurrentTab,polarity)};
            app.AddNLossPanel(CurrentTab).window.UIFigure.Visible=true;
        end
        function app = OutputAddDat(app,~,~,CurrentTab,polarity)
            %gather Cosine Similarity
            app.Data(CurrentTab).CosSim = app.AddNLossPanel(CurrentTab).window.CosSimField.Value/100;
            %gather logical array
            Single = [app.AddNLossPanel(CurrentTab).window.SingleArray(:).Value];
            Double = [app.AddNLossPanel(CurrentTab).window.DoubleArray(:).Value];
            Triple = [app.AddNLossPanel(CurrentTab).window.TripleArray(:).Value];
            Dimer = [app.AddNLossPanel(CurrentTab).window.DimerArray(:).Value];
            switch polarity
                case "positive"
                    app.Data(CurrentTab).AddSelectedPos = [Single';Dimer';Double';Triple'];
                case "negative"
                    app.Data(CurrentTab).AddSelectedNeg = [Single';Dimer';Double';Triple'];
                case "both"
                    app.Data(CurrentTab).AddSelectedPos = [Single(1:12)';Dimer(1:6)';Double(1:8)';Triple(1:4)'];
                    app.Data(CurrentTab).AddSelectedNeg = [Single(13:end)';Dimer(7:end)';Double(9);Triple(5)];
            end
            app.Data(CurrentTab).NeutralSelectedSmol = [app.AddNLossPanel(CurrentTab).window.SmolArray(:).Value]';
            app.Data(CurrentTab).NeutralSelectedCon = [app.AddNLossPanel(CurrentTab).window.ConjugatesArray(:).Value]';
            app.AddNLossPanel(CurrentTab).AddNLossLamp.Color = [0.39,0.83,0.07];
            delete(app.AddNLossPanel(CurrentTab).window.UIFigure);
        end

        %% Augmentation And Reference
        function ConstructAugmentationFigure(app)
            % check if Files are selected
            app.AugmentOptions.window = uifigure("WindowStyle","normal",...
                "Name","Augmentation and Referencing",...
                "Position",[100,100,700,480],...
                "NumberTitle","off");
            % build input Table
            colName={'Groups to Combine:';'Reference Group:'};
            nG = size(app.Data,2);
            RowName=[app.Data(:).GroupName];
            ToolTip={'Define the relationship of the groups for data processing. Groups with the same label are processed together. Combined groups are treated as one group and compared with the others. By specifying a reference group, groups with the same label are normalized to the reference group.'};
            %check if AsignmentArray already exists
            if size(app.augmentationAssignment,1) ~= size(app.Data,2)
                catsB="none"; % Categories for Reference Column
                catsB=repmat(catsB,[nG,1]);
                %generate Assignment String
                AllCats = [string(mat2cell('A':'Z',1,ones(1,26))),string(mat2cell('a':'z',1,ones(1,26)))];
                AllCats(nG+1:end)=[]; % remove Rows > number of Groups
                catsB=categorical(catsB,["none",AllCats]);
                % Categories for Combine Column
                AllCats=categorical(AllCats,AllCats);
                RowName(nG+1:end)=[];
                tdata=table(AllCats',catsB,'VariableNames',colName,'RowNames',RowName);
            else
                tdata=table(removecats(app.augmentationAssignment(:,1)),app.augmentationAssignment(:,2),'VariableNames',colName,'RowNames',RowName);
            end
            app.AugmentOptions.InputTable = uitable(app.AugmentOptions.window,'Data',tdata,"Position",[5,5,690,400],"ColumnEditable",true,"SelectionType","cell","Tooltip",ToolTip);

            app.AugmentOptions.Labels(1) = uilabel(app.AugmentOptions.window,...
                "Text",["Define Group Augmentation";"and Reference Groups"],...
                FontSize=18,...
                FontWeight="bold",...
                Position=[20,420,620,50]);

            app.AugmentOptions.OKButton = uibutton(app.AugmentOptions.window,"push",...
                "Position",[535,420,155,40],...
                Text="OK");
            app.AugmentOptions.mzerrorField = uieditfield(app.AugmentOptions.window,"numeric", ...
                "Position",[435,440,40,20], ...
                "Limits",[0,Inf], ...
                "LowerLimitInclusive","off",...
                "Value",app.mzMergeTol,...
                Tooltip="Lowest mass tolerance to identify features between different groups as the same feature.");
            app.AugmentOptions.Labels(2) = uilabel(app.AugmentOptions.window,"Text","Merging Mass tolerance","Position",[280,440,150,20],HorizontalAlignment="right");

            app.AugmentOptions.mzerrorUnitDrop = uidropdown(app.AugmentOptions.window, ...
                "Items",["ppm","Da"],...
                ItemsData=["ppm","Da"], ...
                Value=app.mzMergeTolUnit,...
                Position=[475,440,50,20],...
                Tooltip="Specify whether a fixed mz error in Da or a relative error in ppm is used.");

            app.AugmentOptions.RTTolField = uieditfield(app.AugmentOptions.window,"numeric", ...
                "Position",[435,415,90,20], ...
                "Limits",[0,Inf], ...
                "ValueDisplayFormat","%.1f s",...
                "Value",app.rtMergeTol, ...
                "RoundFractionalValues","off",...
                ToolTip="Retention time tolerance whether peaks from different groups belong to the same feature.");
            app.AugmentOptions.Labels(3) = uilabel(app.AugmentOptions.window,"Text","Merging Time Tolerance","Position",[280,415,150,20],HorizontalAlignment="right");
            % Callbacks
            app.AugmentOptions.OKButton.ButtonPushedFcn = @(src,event){OutputAugmentationData(app,src,event)};

        end
        function app = OutputAugmentationData(app,~,~)
            AugData = app.AugmentOptions.InputTable.Data;
            app.augmentationAssignment = AugData{:,:};
            app.rtMergeTol = app.AugmentOptions.RTTolField.Value;
            app.mzMergeTol = app.AugmentOptions.mzerrorField.Value;
            app.mzMergeTolUnit = app.AugmentOptions.mzerrorUnitDrop.Value;
            app = AugmentCloseRequest(app);
        end
        function app = AugmentCloseRequest(app)
            close(app.AugmentOptions.window)
            CheckReady(app)
        end
        %% DataBaseCallbacks
        function app=addDataBaseWindow(app)
            app.DataBase = MSDataBase(app);
            app.DataBase.SelectDBFile.ButtonPushedFcn = @(src,event) {SelectDBCall(app,src,event)};
            app.DataBase.GenerateDB.ButtonPushedFcn = @(src,event) {GenerateDatabaseCall(app,src,event)};
            app.DataBase.MS1SearchButton.ButtonPushedFcn = @(src,event) {QueryMS1Call(app,src,event)};
            app.DataBase.MS2SearchButton.ButtonPushedFcn = @(src,event) {QueryMS2Call(app,src,event)};
            app.DataBase.Window.CloseRequestFcn = @(src,event) {CloseDataBaseSearcher(app,src,event)};
            %disable main window
            app.DataProcessingPanel.Enable = "off";
            app.FeatureScalingPanel.Enable = "off";
            app.GeneralOptionsPanel.Enable = "off";
            app.EvaluationPanel.Container.Enable = "off";
            app.SwitchView.Enable = "off";
        end
        function app = SelectDBCall(app,~,~)
            app.DataBase = app.DataBase.SelectDB;
        end
        function app = GenerateDatabaseCall(app,~,~)
            app.DataBase = app.DataBase.GenerateDatabase;
        end

        function app = CloseDataBaseSearcher(app,~,~)
            app.dataBaseFile  = app.DataBase.DataBaseFile;
            %re-enable mainWindow
            app.DataProcessingPanel.Enable = "on";
            app.FeatureScalingPanel.Enable = "on";
            app.GeneralOptionsPanel.Enable = "on";
            app.EvaluationPanel.Container.Enable = "on";
            app.SwitchView.Enable = "on";
            delete(app.DataBase.Window);
        end

        function app = QueryMS1Call(app,~,~)
            d = uiprogressdlg(app.DataBase.Window,'Title','Searching Database','Message','Please Wait','Indeterminate','on');
            app.FeatureData.dataBaseResultsMS1 = app.DataBase.DataBaseMS1Query;
            app.FeatureData = app.FeatureData.BuildIdentificaltionLevelStrings;
            %add results to Result table
            if ~isempty(app.ReportTable)
                if isvalid(app.ReportTable)
                    close(app.ReportTable.Parent)
                end
            end
            app.GenerateReport;
            close(d)
            uialert(app.DataBase.Window,'Search Complete','Success','Icon','success');
        end
        function app = QueryMS2Call(app,~,~)

            % search Database
            if isempty(app.FeatureData.MSnSpectra)
                app = app.EvaluateMSn;
                %update DataBase object
                app.DataBase.Features = app.FeatureData.IdentifierArray;
                if size(app.FeatureData.GroupName,2) < 2
                    app.DataBase.Significant = true(size(app.FeatureData.AverageIntensities));
                    app.DataBase.HighFoldChange = true(size(app.FeatureData.AverageIntensities));
                else
                    IsSignificant = horzcat(app.FeatureData.SignificantFeature{:});
                    IsSignificant = any(IsSignificant <= app.maxP,2);
                    app.DataBase.Significant = IsSignificant;
                    Fold = max(horzcat(app.FeatureData.FullFoldChanges{:}),[],2);
                    app.DataBase.HighFoldChange = Fold >= app.minFold;
                end
                app.DataBase.FoundInGroup = app.FeatureData.FeatureData.InGroup;
            end
            d = uiprogressdlg(app.DataBase.Window,'Title','Searching Database','Message','Please Wait','Indeterminate','on');
            [app.FeatureData.dataBaseResultsMS2,app.FeatureData.DataBaseSpectra,app.FeatureData.DBScoreSpectraID] = app.DataBase.DataBaseMS2Query(app.FeatureData.FeatureMSnSpectra);
            app.FeatureData = app.FeatureData.BuildIdentificaltionLevelStrings;
            % add results to Result table
            if isvalid(app.ReportTable)
                close(app.ReportTable.Parent)
            end
            app.GenerateReport;
            close(d)
            uialert(app.DataBase.Window,'Search Complete','Success','Icon','success');
        end
        %% Evaluation Callbacks
        function app = PlotTICs(app,~,~)
            d = uiprogressdlg(app.MainWindow,'Title','Please Wait','Message','Please Wait','Indeterminate','on');
            delete(app.EvaluationPanel.PlotSpace.Children)
            app.EvaluationPanel.PlotSpace.Title.String = " ";
            app.EvaluationPanel.PlotSpace.XLabel.String = " ";
            app.EvaluationPanel.PlotSpace.YLabel.String = " ";
            % plot all TICs for every Group
            for g=1:numel(app.Data)
                FileNames = app.Data(g).FileNames;
                GroupNames = app.Data(g).GroupName;
                data = app.Data(g).RawDataFileObj.PreviewTICs;
                times = app.Data(g).RawDataFileObj.PreviewTimes;
                numData = size(data,1);
                app.EvaluationPanel.PlotTiles(g) = nexttile(app.EvaluationPanel.PlotSpace);
                for n = 1:numData
                    TIC=data{n,1};
                    plot(app.EvaluationPanel.PlotTiles(g),times{n},TIC)
                    colororder(app.EvaluationPanel.PlotTiles(g),"gem12")
                    app.EvaluationPanel.PlotTiles(g).NextPlot = 'add';
                end
                title(app.EvaluationPanel.PlotTiles(g),"Sample TICs for " + GroupNames)
                legend(app.EvaluationPanel.PlotTiles(g),FileNames,Interpreter='none')
                drawnow limitrate
            end
            xlabel(app.EvaluationPanel.PlotSpace,"RT [sec]")
            ylabel(app.EvaluationPanel.PlotSpace,"Intensity [arb]")
            close(d)
        end

        function app = PlotROIs(app,~,~)
            d = uiprogressdlg(app.MainWindow,'Title','Please Wait','Message','Please Wait','Indeterminate','on');
            delete(app.EvaluationPanel.PlotSpace.Children)
            app.EvaluationPanel.PlotSpace.Title.String = " ";
            app.EvaluationPanel.PlotSpace.XLabel.String = " ";
            app.EvaluationPanel.PlotSpace.YLabel.String = " ";
            % plot Found ROI Profiles for every Group
            for g=1:size(app.Data,2)
                data = app.Data(g).ROIDataFileObj.ROIMat;
                time = app.Data(g).ROIDataFileObj.timeVec;
                mzroi = app.Data(g).ROIDataFileObj.ROImzVec;
                %set first and last row to 0, to prevent plot artefacts
                data = mat2cell(data,app.Data(g).nScansPadded,size(data,2));
                time = mat2cell(time,app.Data(g).nScansPadded,size(time,2));
                for j=1:size(data,1)
                    idx = time{j,1} == 0;
                    time{j,1}(idx) = [];
                    data{j,1}(idx,:) = [];
                    data{j,1}(1,:) = 0;
                    data{j,1}(end,:) = 0;
                end
                data = vertcat(data{:});
                time = vertcat(time{:});
                app.EvaluationPanel.PlotTiles(g) = nexttile(app.EvaluationPanel.PlotSpace);
                plot(app.EvaluationPanel.PlotTiles(g),time,data)
                colororder(app.EvaluationPanel.PlotTiles(g),"gem12")
                mzroi=flip(mzroi);
                for n=size(app.EvaluationPanel.PlotTiles(g).Children,1):-1:1
                    app.EvaluationPanel.PlotTiles(g).Children(n).DataTipTemplate.DataTipRows(1).Label="RT:";
                    app.EvaluationPanel.PlotTiles(g).Children(n).DataTipTemplate.DataTipRows(2).Label="Intensity:";
                    app.EvaluationPanel.PlotTiles(g).Children(n).DataTipTemplate.DataTipRows(3) = dataTipTextRow("ROI m/z:",repmat(mzroi(n),size(time,1),1),'%.6g');
                end
                title(app.EvaluationPanel.PlotTiles(g),"ROIs of " + app.Data(g).GroupName);
            end
            xlabel(app.EvaluationPanel.PlotSpace,"RT [sec]")
            ylabel(app.EvaluationPanel.PlotSpace,"Intensity [arb]")
            drawnow
            close(d)
        end

        function app = PlotIntResults(app,~,~)
            delete(app.EvaluationPanel.PlotSpace.Children)
            app.EvaluationPanel.PlotSpace.Title.String = " ";
            app.EvaluationPanel.PlotSpace.XLabel.String = " ";
            app.EvaluationPanel.PlotSpace.YLabel.String = " ";
            % ask for specific features
            pCrit = app.maxP;
            [masses,SpecificIndex] = IntResultsSelectionGUI(length(app.FeatureData.NameStringArray));
            if isempty(masses)
                return
            end
            SelectedFeat = CompileSelection(app.FeatureData,masses,SpecificIndex,pCrit);

            %check resulting number of plots; throw warning if a LOT
            numPlots = sum(SelectedFeat);
            if numPlots > 100
                selection = uiconfirm(app.MainWindow,"A total of "+numPlots+" plots will be generated.",'Confirm Continue',...
                    'Options',{'Continue','Reselect Features','Cancel'}, ...
                    'DefaultOption',2,'CancelOption',3,'Icon','warning');
                switch selection
                    case 'Reselect Features'
                        [masses,SpecificIndex] = IntResultsSelectionGUI(length(app.FeatureData.NameStringArray));
                        if isempty(masses)
                            return
                        end
                        SelectedFeat = CompileSelection(app.FeatureData,masses,SpecificIndex,pCrit);
                    case 'Cancel'
                        return
                end
            end
            FeatNumber = 1:1:length(app.FeatureData.NameStringArray);
            FeatNumber(~SelectedFeat)=[];
            FeatName = app.FeatureData.NameStringArray(SelectedFeat) + ", "+app.FeatureData.InGroup(SelectedFeat);
            GroupNames=[app.FeatureData.GroupName]; %get group labels, when augmented groups replace combined name with abstract name
            indices = contains(GroupNames,"+");
            GroupNames(indices) = app.FeatureData.AbstractGroupName(indices);
            XIC = app.FeatureData.XIC(SelectedFeat,:);

            %build indices to original Group and gather time data
            OriginalGroup = app.FeatureData.OriginalGroup;
            GroupNamesNonAbstract=[app.FeatureData.GroupName]; %get group labels
            timeData = cell(size(GroupNamesNonAbstract));
            OGnames = [app.Data.GroupName];
            for z=1:size(OriginalGroup,2)
                SplitName = strsplit(GroupNamesNonAbstract(z),"+");
                index = contains(OGnames,SplitName);
                timeData{z} = {app.Data(index).ROIDataFileObj.timeVec};
                OriginalGroup(OriginalGroup(:,z)~="",z)=z;
            end
            OriginalGroup = str2double(OriginalGroup);
            OriginalGroup = OriginalGroup(SelectedFeat,:);
            for q=1:numel(FeatNumber)
                ID = OriginalGroup(q,:);
                ID(isnan(ID)) = [];
                figure('Name',"Feature No. " +FeatNumber(q),'NumberTitle','off')
                hold on
                textstore = cell(1,numel(app.FeatureData.NumberOfFilesArray));
                currentXICs = XIC(q,:);
                indices = ~cellfun(@isempty,currentXICs);
                for g=ID
                    text = 1:1:size(currentXICs,2);
                    text(:,any(indices==0,1))=[];
                    text = GroupNames(g) + " Repetition " + text;
                    textstore{g} = text;
                    if isempty(indices)
                        continue
                    end
                    for w = 1:size(currentXICs,2)
                        if indices(w) == 1
                            area(currentXICs{1,w}(:,2),currentXICs{1,w}(:,1),'FaceAlpha',0.25)
                        end
                    end
                end
                text = horzcat(textstore{:});
                xlabel("RT [sec]")
                ylabel("Intensity [arb]")
                title(FeatName(q,1))
                legend(text)
                drawnow
            end
            function [check,indices] = IntResultsSelectionGUI(numFeat)
                % Create figure
                h.f = uifigure("Name","Select Features to plot",'units','pixels','position',[400,400,300,150],...
                    'toolbar','none','menu','none');
                h.checked = [];
                % Create check boxes
                %only unique
                h.c(1) = uicheckbox(h.f, 'position',[25,55,130,20],Text='Unique Features');
                %only non unique
                h.c(2) = uicheckbox(h.f, 'position',[155,55,130,20],Text='non Uniqe Features');
                %only significant
                h.c(3) = uicheckbox(h.f, 'position',[25,80,130,20],Text='Significant Features');
                %specific Feature
                h.c(4) = uieditfield(h.f,"text",...
                    Position=[170,110,80,20],...
                    Value="1-"+numFeat,Tooltip="Specify which Features to plot. Individual features can be separated with ,. To select a contiguous number use -. For example, '2,10-14, 20' will plot features 2,10,11,12,13,14 and 20.");
                h.l =  uilabel(h.f,"Text","Select Specific Features:",...
                    "Position",[25,110,155,20],...
                    "HorizontalAlignment","left");
                % Create OK push button
                h.p = uibutton(h.f,"Text","Plot Features", ...
                    "Position",[100,5,100,40],...S
                    FontWeight="bold");
                h.p.ButtonPushedFcn = @p_call;
                uiwait(h.f);
                delete(h.f);
                if isempty(h.checked)
                    check = [];
                    indices = [];
                    return
                end
                check  = h.checked;
                indices = h.indices;
                % Push button callback
                function p_call(varargin)
                    vals = get(h.c,'Value');
                    % Split the string using ','
                    segments = strsplit(vals{4}, {',',' '});
                    vals(4) = [];
                    % Initialize an empty vector
                    output = [];
                    % Process each segment
                    for i = 1:length(segments)
                        segment = strtrim(segments{i}); % Remove leading/trailing spaces

                        % Check if the segment contains a '-'
                        if contains(segment, '-')
                            % Split the segment using '-'
                            range = strsplit(segment, '-');
                            % Extract the start and end values
                            start_val = str2double(range{1});
                            end_val = str2double(range{2});
                            % Generate the range vector and append it to the output
                            range_vector = (start_val:end_val)';
                            output = [output; range_vector];
                        else
                            % Single number segment, convert to double and append to the output
                            number = str2double(segment);
                            output = [output; number];
                        end
                    end
                    checked = vertcat(vals{:});
                    h.indices = output;
                    h.checked = checked;
                    close(h.f)
                end
            end

            function SelectedFeat = CompileSelection(FeatureData,check,SpecificIndex,pCrit)
                SelectedFeat = true(size(FeatureData.UniqueFeature,1),3);
                %only non Unique
                if check(1) == true
                    SelectedFeat(:,1) = any(FeatureData.UniqueFeature,2);
                end
                %only Unique
                if check(2) == true
                    SelectedFeat(:,2) = ~any(FeatureData.UniqueFeature,2);
                end
                %only significant
                if check(3) == true
                    SelectedFeat(:,3) = ~any(horzcat(FeatureData.SignificantFeature{:}) <= pCrit,2);
                end
                SelectedFeat = ~any(SelectedFeat==0,2);
                Specific = false(size(SelectedFeat));
                Specific(SpecificIndex) = true;
                SelectedFeat = SelectedFeat == true & Specific == true;
            end
        end

        function [masses,tolerance,tolUnit] = EICSelectionGUI(app,CurrentTab)
            % Create figure
            h.f = uifigure("Name","Select EICs to plot",'units','pixels','position',[400,400,300,150],...
                'toolbar','none','menu','none');

            %specifiy masses
            h.mField = uieditfield(h.f,"text",...
                Position=[100,110,180,20],...
                Value="194.08",Tooltip="Specify which M to plot. Individual masses can be separated with , or ;");
            h.label(1) =  uilabel(h.f,"Text","Specifiy mass :",...
                "Position",[10,110,100,20],...
                "HorizontalAlignment","left");
            % tolerance and unit
            h.tolField = uieditfield(h.f,"numeric", ...
                "Position",[145,80,40,20], ...
                "Limits",[0,Inf], ...
                "LowerLimitInclusive","off",...
                "Value",app.Data(CurrentTab).mzerror,...
                Tooltip="mass tolerance for EIC generation.");
            h.tolDrop = uidropdown(h.f, ...
                "Items",["ppm","Da"],...
                ItemsData=["ppm","Da"], ...
                Value=app.Data(CurrentTab).mzErrorUnit,...
                Position=[185,80,60,20],...
                Tooltip="Specify whether a fixed mz error in Da or a relative error in ppm is used.");
            h.label(2) =  uilabel(h.f,"Text","Specifiy mass tolerance:",...
                "Position",[10,80,135,20],...
                "HorizontalAlignment","left");

            % Create OK push button
            h.p = uibutton(h.f,"Text","Plot EICs", ...
                "Position",[100,5,100,40],...
                FontWeight="bold");
            h.masses = [];
            h.tolerance = [];
            h.tolUnit = "";
            h.p.ButtonPushedFcn = @p_exit;
            uiwait(h.f);
            masses  = h.masses;
            tolerance = h.tolerance;
            tolUnit = h.tolUnit;
            delete(h.f);
            % Push button callback
            function p_exit(varargin)
                vals = get(h.mField,'Value');
                % Split the string using ','
                segments = strsplit(vals, {',',', ',' ,',';','; ',' ;',' '});
                % Process each segment
                segments = strtrim(segments); % Remove leading/trailing spaces, convert to double
                output = str2double(segments);
                h.masses = output;
                h.tolerance = h.tolField.Value;
                h.tolUnit = h.tolDrop.Value;
                close(h.f)
            end
        end

        function app = PlotEntropy(app,~,~)
            delete(app.EvaluationPanel.PlotSpace.Children)
            app.EvaluationPanel.PlotSpace.Title.String = " ";
            app.EvaluationPanel.PlotSpace.XLabel.String = " ";
            app.EvaluationPanel.PlotSpace.YLabel.String = " ";
            preMedianEntropy=[app.Data.MedianEntropy];
            GroupNames = [app.Data.GroupName];
            GroupEntropy = mat2cell(app.FeatureData.EntropyArray,size(app.FeatureData.EntropyArray,1),app.FeatureData.NumberOfFilesArray);
            for g=1:size(GroupEntropy,2)
                allEntropy = GroupEntropy{1,g};
                postMedianEntropy =median(allEntropy,"all");
                app.EvaluationPanel.PlotTiles(g) = nexttile(app.EvaluationPanel.PlotSpace);
                histogram(app.EvaluationPanel.PlotTiles(g),allEntropy,BinWidth=0.025)
                title(app.EvaluationPanel.PlotTiles(g),"Entropy Distribution for " + GroupNames(g))
                xL=xlim(app.EvaluationPanel.PlotTiles(g));
                yL=ylim(app.EvaluationPanel.PlotTiles(g));
                text(app.EvaluationPanel.PlotTiles(g),0.95*xL(2),0.95*yL(2),["Pre Filter Median Entropy: "+ preMedianEntropy(g);"Post Filter Median Entropy: "+ postMedianEntropy],'HorizontalAlignment','right','VerticalAlignment','top','FontSize',8);
            end
            xlabel(app.EvaluationPanel.PlotSpace,"Peak Entropy")
            ylabel(app.EvaluationPanel.PlotSpace,"Counts per bin")
        end

        function app = PlotSN(app,~,~)
            delete(app.EvaluationPanel.PlotSpace.Children)
            app.EvaluationPanel.PlotSpace.Title.String = " ";
            app.EvaluationPanel.PlotSpace.XLabel.String = " ";
            app.EvaluationPanel.PlotSpace.YLabel.String = " ";
            GroupNames = [app.Data.GroupName];
            GroupSN = mat2cell(app.FeatureData.Signal2NoiseArray,size(app.FeatureData.Signal2NoiseArray,1),app.FeatureData.NumberOfFilesArray);
            for g=1:size(GroupNames,2)
                allSN=GroupSN{1,g};
                allSN(allSN==0)=NaN;
                app.EvaluationPanel.PlotTiles(g) = nexttile(app.EvaluationPanel.PlotSpace);
                histogram(app.EvaluationPanel.PlotTiles(g),allSN,BinWidth=5)
                title(app.EvaluationPanel.PlotTiles(g),"S/N Distribution for " + GroupNames(g))
            end
            xlabel(app.EvaluationPanel.PlotSpace,"Signal to Noise")
            ylabel(app.EvaluationPanel.PlotSpace,"Counts per bin")
        end

        function app = PlotRejects(app,~,~)
            delete(app.EvaluationPanel.PlotSpace.Children)
            app.EvaluationPanel.PlotSpace.Title.String = " ";
            app.EvaluationPanel.PlotSpace.XLabel.String = " ";
            app.EvaluationPanel.PlotSpace.YLabel.String = " ";
            GroupNames = [app.Data.GroupName];
            RejectedFeat=[[app.Data.MinWidthFiltered];[app.Data.MaxWidthFiltered];[app.Data.SNFiltered];[app.Data.EntropyFiltered];[app.Data.OccurenceFiltered];[app.Data.AdductFiltered]];
            app.EvaluationPanel.PlotTiles(1) = nexttile(app.EvaluationPanel.PlotSpace,app.EvaluationPanel.PlotSpace.GridSize);
            cause= categorical(["Min.Peak Width"; "Max.Peak Width"; "Min.Signal to Noise"; "Entropy";"Occurence";"Filtered Adducts"]);
            cause=reordercats(cause,{'Min.Peak Width','Max.Peak Width','Min.Signal to Noise','Entropy','Occurence','Filtered Adducts'});
            bar(app.EvaluationPanel.PlotTiles(1),cause,RejectedFeat);
            xtickangle(app.EvaluationPanel.PlotTiles(1),0);
            ylim(app.EvaluationPanel.PlotTiles(1),[0,max(RejectedFeat,[],'all')+max(RejectedFeat,[],'all')*0.1])
            title(app.EvaluationPanel.PlotTiles(1),"Number of rejected Peaks by filter")
            ylabel(app.EvaluationPanel.PlotTiles(1),"Number of Peaks Rejected")
            legend(app.EvaluationPanel.PlotTiles(1),GroupNames)
            xtips1 = [app.EvaluationPanel.PlotTiles(1).Children.XEndPoints];
            ytips1 = [app.EvaluationPanel.PlotTiles(1).Children.YEndPoints];
            labels1 = string([app.EvaluationPanel.PlotTiles(1).Children.YData]);
            text(app.EvaluationPanel.PlotTiles(1),xtips1,ytips1,labels1,'HorizontalAlignment','center','VerticalAlignment','bottom','FontSize',8);
        end

        function app = PlotScatter(app,~,~)
            delete(app.EvaluationPanel.PlotSpace.Children)
            app.EvaluationPanel.PlotSpace.Title.String = " ";
            app.EvaluationPanel.PlotSpace.XLabel.String = " ";
            app.EvaluationPanel.PlotSpace.YLabel.String = " ";
            %scatter retention times vs mz, size: height or area
            GroupNames=[app.FeatureData.GroupName]; %get group labels, when augmented groups replace combined name with abstract name
            idx = contains(GroupNames,"+");
            GroupNames(idx) = app.FeatureData.AbstractGroupName(idx);

            unscaledData = app.FeatureData.CleanFullIntensityArray;
            % calculate unscaled averages:
            unscaledData(unscaledData==0)=NaN;
            unscaledData = mat2cell(unscaledData,size(unscaledData,1),app.FeatureData.NumberOfFilesArray);
            unscaledData=cellfun(@(x) mean(x,2,'omitnan'),unscaledData,'UniformOutput',false);
            unscaledData = horzcat(unscaledData{:});
            % all TICs
            TIC = cell(1,size(app.Data,2));
            times = cell(1,size(app.Data,2));
            for w = 1:size(app.Data,2)
                times(1,w) = app.Data(w).RawDataFileObj.PreviewTimes(1,1);
                TIC(1,w) = app.Data(w).RawDataFileObj.PreviewTICs(1,1);
            end

            scaleddata=app.FeatureData.AverageIntensities;
            %get mz values and Time values from Feature Label
            mz=app.FeatureData.IdentifierArray(:,1);
            rt=app.FeatureData.IdentifierArray(:,2);
            % unscaled data
            figure("Name","Scatter Plot, unscaled");
            maxMZ = {max(mz,[],"all")*0.6};
            maxMZ = repmat(maxMZ,1,numel(TIC));
            TIC = cellfun(@(x,y) rescale(x,0,y),TIC,maxMZ,'UniformOutput',false);
            app.ScatterPlot(1) = gca;
            hold on
            for n=1:width(TIC)
                plot(times{1,n},TIC{1,n},'Color',[0.9 0.9 0.9],'HandleVisibility',"off",'HitTest',"off")
            end
            rdata=rescale(unscaledData,0.1,1500);
            for g=1:size(GroupNames,2)
                scatter(app.ScatterPlot(1),rt,mz,rdata(:,g),'filled');
                app.ScatterPlot(1).Children(1).CData = repmat(app.colorPalette(g,:),size(mz,1),1);
                app.ScatterPlot(1).Children(1).MarkerFaceAlpha = "flat";
                app.ScatterPlot(1).Children(1).AlphaDataMapping = "none";
                app.ScatterPlot(1).Children(1).AlphaData = repmat(0.2,size(mz,1),1);
                app.ScatterPlot(1).Children(1).DataTipTemplate.DataTipRows(1).Label="RT:";
                app.ScatterPlot(1).Children(1).DataTipTemplate.DataTipRows(2).Label="mz:";
                app.ScatterPlot(1).Children(1).DataTipTemplate.DataTipRows(3)=dataTipTextRow('Feature' , app.FeatureData.NameStringArray);
                app.ScatterPlot(1).Children(1).DataTipTemplate.DataTipRows(4)=dataTipTextRow('unscaled Average Peak Value' , unscaledData(:,g),'%.4e');
                app.ScatterPlot(1).Children(1).DataTipTemplate.DataTipRows(5) = [];
            end

            title("Scatter Plot mz vs RT circle size corresponds to unscaled average intensity")
            legend(GroupNames,Interpreter='none',AutoUpdate='off')
            xlabel("RT [sec]")
            ylabel("mz [Da]")

            % plot scaled data if scaling was applied
            if strcmp(app.transformationType,app.dataScalingType) == false
                % scaled data
                figure("Name","Scatter Plot, scaled");
                app.ScatterPlot(2) = gca;
                hold on
                for n=1:width(TIC)
                    plot(times{1,n},TIC{1,n},'Color',[0.9 0.9 0.9],'HandleVisibility',"off",'HitTest',"off")
                end
                set(gca,'ColorOrderIndex',1)
                rdata=rescale(scaleddata,0.1,100);
                for g=1:size(GroupNames,2)
                    scatter(rt,mz,rdata(:,g),'filled')
                    app.ScatterPlot(2).Children(1).CData = repmat(app.colorPalette(g,:),size(mz,1),1);
                    app.ScatterPlot(2).Children(1).AlphaData = repmat(0.2,size(mz,1),1);
                    app.ScatterPlot(2).Children(1).MarkerFaceAlpha = "flat";
                    app.ScatterPlot(2).Children(1).AlphaDataMapping = "none";
                    app.ScatterPlot(2).Children(1).DataTipTemplate.DataTipRows(1).Label="RT:";
                    app.ScatterPlot(2).Children(1).DataTipTemplate.DataTipRows(2).Label="mz:";
                    app.ScatterPlot(2).Children(1).DataTipTemplate.DataTipRows(3)=dataTipTextRow('Feature' , app.FeatureData.NameStringArray);
                    app.ScatterPlot(2).Children(1).DataTipTemplate.DataTipRows(4)=dataTipTextRow('unscaled Average Peak Value' , unscaledData(:,g),'%.4e');
                    app.ScatterPlot(2).Children(1).DataTipTemplate.DataTipRows(5) = [];
                end
                title("Scatter Plot mz vs RT, circle size corresponds to scaled average intensity")
                legend(GroupNames,Interpreter='none',AutoUpdate='off')
                xlabel("RT [sec]")
                ylabel("mz [Da]")
            end


        end

        function app = PlotCloud(app,~,~)
            delete(app.EvaluationPanel.PlotSpace.Children)
            app.EvaluationPanel.PlotSpace.Title.String = " ";
            app.EvaluationPanel.PlotSpace.XLabel.String = " ";
            app.EvaluationPanel.PlotSpace.YLabel.String = " ";
            %get data
            foldCell=app.FeatureData.FullFoldChanges;
            pCell=app.FeatureData.SignificantFeature;
            VSNames = app.FeatureData.VSNaming;
            %get mz values and Time values from Feature Label
            mz=app.FeatureData.IdentifierArray(:,1);
            rt=app.FeatureData.IdentifierArray(:,2);
            % all TICs
            TIC = cell(2,size(app.Data,2));
            for w = 1:size(app.Data,2)
                TIC(1,w) = app.Data(w).RawDataFileObj.PreviewTICs(1,1);
                TIC(2,w) = app.Data(w).RawDataFileObj.PreviewTimes(1,1);
            end
            %pad to same size
            nScans = max(cellfun(@numel,TIC(:,2)));
            TIC = cellfun(@(X) padarray(X,size(X,1)-nScans,0,"post"),TIC,UniformOutput=false);
            Times=vertcat(TIC{2,:});
            TIC=vertcat(TIC{1,:});
            for n=1:size(VSNames,2)
                fold = foldCell{n};
                fold = log2(fold);
                fold = real(fold);
                fold(fold==Inf)=0;
                fold(fold==-Inf)=0;
                p = pCell{n};
                PlotNames=VSNames{n};
                for g = 1:size(PlotNames,2)
                    figure("Name","Cloud Plot","NumberTitle","off");
                    hold on
                    mzDat=mz;
                    rtDat=rt;
                    %change mz sign when foldchange < 0
                    mzDat(fold(:,g)<0)=mzDat(fold(:,g)<0)*-1;
                    SizeDat = abs(fold(:,g));
                    ColorDat = p(:,g);
                    idx = SizeDat~=0;
                    if ~all(idx)
                        TIC = rescale(TIC,0,100);
                    else
                        TIC = rescale(TIC,0,max(mz(idx),[],"all")*0.7);
                    end
                    plot(Times,TIC,'Color',[0.9 0.9 0.9],'HandleVisibility',"off",'HitTest',"off")
                    plot(Times,-TIC,'Color',[0.9 0.9 0.9],'HandleVisibility',"off",'HitTest',"off")
                    set(gca,'ColorOrderIndex',1)
                    bubblechart(rtDat(idx),mzDat(idx),SizeDat(idx),ColorDat(idx))
                    colormap(gca,"copper")
                    app.CloudPlot(n+g-1) = gca;
                    bubblesize(app.CloudPlot(n+g-1),[1 25])
                    bubblelegend(app.CloudPlot(n+g-1),'Fold Change','Location','westoutside')
                    app.CloudPlot(n+g-1).Children(1).DataTipTemplate.DataTipRows(1).Label="Time:";
                    app.CloudPlot(n+g-1).Children(1).DataTipTemplate.DataTipRows(2).Label="mz:";
                    app.CloudPlot(n+g-1).Children(1).DataTipTemplate.DataTipRows(3)=dataTipTextRow("Fold Change:",fold(idx,g));
                    app.CloudPlot(n+g-1).Children(1).DataTipTemplate.DataTipRows(4)=dataTipTextRow("p-Value:",ColorDat(idx));
                    lebar = colorbar;
                    lebar.Label.String = 'p-Value';
                    title("Fold Changes of "+PlotNames(g),Interpreter="none")
                    ylabel("mz [Da]")
                    xlabel("Time [sec]")
                end

            end
        end

        function app = PlotProbability(app,~,~)
            delete(app.EvaluationPanel.PlotSpace.Children)
            app.EvaluationPanel.PlotSpace.Title.String = " ";
            app.EvaluationPanel.PlotSpace.XLabel.String = " ";
            app.EvaluationPanel.PlotSpace.YLabel.String = " ";
            %scatter retention times vs mz, size: height or area
            GroupNames=[app.FeatureData.GroupName]; %get group labels, when augmented groups replace combined name with abstract name
            idx = contains(GroupNames,"+");
            GroupNames(idx) = app.FeatureData.AbstractGroupName(idx);
            app.EvaluationPanel.PlotTiles(1) = nexttile(app.EvaluationPanel.PlotSpace,app.EvaluationPanel.PlotSpace.GridSize);
            data=app.FeatureData.AverageIntensities; % Feature average Height or Area data
            data(data==0)=NaN;
            normplot(app.EvaluationPanel.PlotTiles(1),data)
            legend(app.EvaluationPanel.PlotTiles(1),GroupNames)
        end

        function app = PlotFeatureDistribution(app,~,~)
            delete(app.EvaluationPanel.PlotSpace.Children)
            app.EvaluationPanel.PlotSpace.Title.String = " ";
            app.EvaluationPanel.PlotSpace.XLabel.String = " ";
            app.EvaluationPanel.PlotSpace.YLabel.String = " ";
            %plot distribution of original data and transformed data
            GroupNames=[app.FeatureData.GroupName]; %get group labels, when augmented groups replace combined name with abstract name
            idx = contains(GroupNames,"+");
            GroupNames(idx) = app.FeatureData.AbstractGroupName(idx);
            %scaled data
            scaled = app.FeatureData.IntensityArray;
            scaled(scaled==0)=NaN;
            scaled = mat2cell(scaled, size(scaled,1),app.FeatureData.NumberOfFilesArray);
            for g=1:size(GroupNames,2)
                app.EvaluationPanel.PlotTiles(g) = nexttile(app.EvaluationPanel.PlotSpace);
                histogram(app.EvaluationPanel.PlotTiles(g),scaled{g},'BinMethod','sqrt')
                title(app.EvaluationPanel.PlotTiles(g),"Feature Intensities for " + GroupNames(g))
                %test for normal distribution
                zscores = scaled{g};
                zscores(any(isnan(zscores),2),:)=[];
                zscores = zscore(zscores);
                [h, p] = kstest(reshape(zscores,[],1), 'Alpha', 0.05);
                p = round(p,3);
                Significance = "Kolmogorov-Smirnov test, alpha: 0.05";
                if h
                    Significance(2,1) = "The values are not normally distributed.";
                else
                    Significance(2,1) =  "The values are normally distributed.";
                end
                Significance(3,1) = "Test p-value: " + p;
                xL=xlim(app.EvaluationPanel.PlotTiles(g));
                yL=ylim(app.EvaluationPanel.PlotTiles(g));
                text(app.EvaluationPanel.PlotTiles(g),0.95*xL(2),0.95*yL(2),Significance,'HorizontalAlignment','right','VerticalAlignment','top','FontSize',8);
                xlh = xlabel(app.EvaluationPanel.PlotTiles(g),"Intensity bin",'VerticalAlignment','top','HorizontalAlignment','center');
                xlh.Position(2) = xlh.Position(2) - abs(xlh.Position(2) * 0.1);
                ylabel(app.EvaluationPanel.PlotTiles(g),"Counts per bin")
            end

        end

        function app = PlotVolcano(app,~,~)
            delete(app.EvaluationPanel.PlotSpace.Children)
            app.EvaluationPanel.PlotSpace.Title.String = " ";
            app.EvaluationPanel.PlotSpace.XLabel.String = " ";
            app.EvaluationPanel.PlotSpace.YLabel.String = " ";
            %get data
            foldCell=app.FeatureData.FullFoldChanges;
            pCell=app.FeatureData.SignificantFeature;
            GroupLabels=[app.FeatureData.GroupName]; %get group labels, when augmented groups replace combined name with abstract name
            idx = contains(GroupLabels,"+");
            GroupLabels(idx) = app.FeatureData.AbstractGroupName(idx);
            VSNames = app.FeatureData.VSNaming;
            FeatLabel=app.FeatureData.NameStringArray;
            ISUnique = any(app.FeatureData.UniqueFeature,2);
            %% transform min fold change
            FoldBorder = app.minFold;
            switch app.transformationType
                case 'logn'
                    FoldBorder = log(FoldBorder);
                case 'log10'
                    FoldBorder = log10(FoldBorder);
                case 'power'
                    FoldBorder = sqrt(FoldBorder);
                case 'cube'
                    FoldBorder = FoldBorder.^(1/3);
                case 'reciprocal'
                    FoldBorder = FoldBorder.^-1;
            end
            for n=1:size(GroupLabels,2)
                figure("Name","Volcano Plot for "+ GroupLabels(n),"NumberTitle","off");
                app.VulcanoPlot(n) = gca;
                fold = foldCell{n};
                fold=log2(fold);
                fold=real(fold);
                fold(fold==Inf)=0;
                fold(fold==-Inf)=0;
                fold(isnan(fold))=0;
                p = -log10(pCell{n});
                PlotNames=VSNames{n};
                hold on
                for g = 1:size(PlotNames,2)
                    scatter(fold(~ISUnique,g),p(~ISUnique,g),'filled','o')
                    app.VulcanoPlot(n).Children(1).CData = repmat(app.colorPalette(g,:),size(fold(~ISUnique,g),1),1);
                    app.VulcanoPlot(n).Children(1).MarkerFaceAlpha = "flat";
                    app.VulcanoPlot(n).Children(1).AlphaDataMapping = "none";
                    app.VulcanoPlot(n).Children(1).AlphaData = repmat(0.2,size(fold(~ISUnique,g),1),1);
                    app.VulcanoPlot(n).Children(1).DataTipTemplate.DataTipRows(1).Label="log2 Fold Change:";
                    app.VulcanoPlot(n).Children(1).DataTipTemplate.DataTipRows(2).Label="-log10 p:";
                    app.VulcanoPlot(n).Children(1).DataTipTemplate.DataTipRows(3)=dataTipTextRow("Feature:",FeatLabel);
                    app.VulcanoPlot(n).Children(1).DataTipTemplate.DataTipRows(4) = [];
                end
                title("Fold Changes of " + GroupLabels(n),Interpreter="none")
                xlabel("log2 FoldChange")
                ylabel("-log10 p")
                legend(PlotNames,Interpreter="none",AutoUpdate="off")
                xlim([min(fold(~ISUnique))-0.1,max(fold(~ISUnique))+0.1])
                xline(log2(FoldBorder),'-.','HandleVisibility','off')
                xline(-log2(FoldBorder),'-.','HandleVisibility','off')
                yline(-log10(app.maxP),'--r',"p Critical",'HandleVisibility','off')
                hold off
            end
        end

        function app = PlotpDistribution(app,~,~)
            delete(app.EvaluationPanel.PlotSpace.Children)
            app.EvaluationPanel.PlotSpace.Title.String = " ";
            app.EvaluationPanel.PlotSpace.XLabel.String = " ";
            app.EvaluationPanel.PlotSpace.YLabel.String = " ";
            %plot distribution of original data and transformed data
            GroupLabels=[app.FeatureData.GroupName]; %get group labels, when augmented groups replace combined name with abstract name
            idx = contains(GroupLabels,"+");
            GroupLabels(idx) = app.FeatureData.AbstractGroupName(idx);
            %define size of plot for each group
            Fullsize = app.EvaluationPanel.PlotSpace.GridSize;
            numGroups = numel(GroupLabels);
            ColumnSize = Fullsize(2)/numGroups;
            RowSize = Fullsize(1);
            %scaled data
            pCell = app.FeatureData.SignificantFeature;
            for g=1:size(GroupLabels,2)
                app.EvaluationPanel.PlotTiles(g) = nexttile(app.EvaluationPanel.PlotSpace,[RowSize,ColumnSize]);
                histogram(app.EvaluationPanel.PlotTiles(g),pCell{g},'BinMethod','sqrt')
                title(app.EvaluationPanel.PlotTiles(g),"p-Values for " + GroupLabels(g))
                xlh = xlabel(app.EvaluationPanel.PlotTiles(g),"p-Value bin",'VerticalAlignment','top','HorizontalAlignment','center');
                xlh.Position(2) = xlh.Position(2) - abs(xlh.Position(2) * 0.1);
                ylabel(app.EvaluationPanel.PlotTiles(g),"Counts per bin")
            end
        end

        function app = PlotHeatmap(app,~,~)
            delete(app.EvaluationPanel.PlotSpace.Children)
            app.EvaluationPanel.PlotSpace.Title.String = " ";
            app.EvaluationPanel.PlotSpace.XLabel.String = " ";
            app.EvaluationPanel.PlotSpace.YLabel.String = " ";
            SampleData=app.FeatureData.IntensityArray;
            GroupData=vertcat(app.FeatureData.AverageIntensities);
            GroupData(isnan(GroupData))=0;
            GroupLabels=[app.FeatureData.GroupName]; %get group labels, when augmented groups replace combined name with abstract name
            idx = contains(GroupLabels,"+");
            GroupLabels(idx) = app.FeatureData.AbstractGroupName(idx);
            %correlation between Samples
            corrSamp=corrcoef(SampleData);
            %Correlation between Groups
            corrGroup=corrcoef(GroupData);
            %plots
            %GroupCorrelation
            figure("Name","Correlation Heatmap");
            h1=heatmap(GroupLabels,GroupLabels,corrGroup);
            h1.NodeChildren(3).XAxis.TickLabelInterpreter = 'none';
            h1.NodeChildren(3).YAxis.TickLabelInterpreter = 'none';
            title('Correlation between Groups')
            %SampleCorrelation
            SampleLabels = vertcat(app.FeatureData.CombinedSampleNames{:});
            %check sample name size and replace when bigger than 20
            val = max(arrayfun(@(x) strlength(x), SampleLabels));
            if val >= 20
                SampleLabels = [];
                for n=1:numel(app.FeatureData.NumberOfFilesArray)
                    tempLabel = repmat(GroupLabels(n), app.FeatureData.NumberOfFilesArray(n),1);
                    tempLabel = tempLabel + " Repetition " + (1:1:app.FeatureData.NumberOfFilesArray(n))';
                    SampleLabels = [SampleLabels;tempLabel];
                end
            end
            figure("Name","Correlation Heatmap");
            h2=heatmap(SampleLabels,SampleLabels,corrSamp);
            h2.NodeChildren(3).XAxis.TickLabelInterpreter = 'none';
            h2.NodeChildren(3).YAxis.TickLabelInterpreter = 'none';
            title('Correlation between Samples')
        end

        function app = PlotPCA(app,~,~)
            d = uiprogressdlg(app.MainWindow,'Title','Performing Proncipal Component Analysis','Message','Please Wait','Indeterminate','on');
            delete(app.EvaluationPanel.PlotSpace.Children)
            app.EvaluationPanel.PlotSpace.Title.String = " ";
            app.EvaluationPanel.PlotSpace.XLabel.String = " ";
            app.EvaluationPanel.PlotSpace.YLabel.String = " ";
            PerformPCA(app)
            close(d)
        end

        function app = PlotISdeviation(app,~,~)
            delete(app.EvaluationPanel.PlotSpace.Children)
            app.EvaluationPanel.PlotSpace.Title.String = "Internal Standard mass deviation";
            app.EvaluationPanel.PlotSpace.XLabel.String = " ";
            app.EvaluationPanel.PlotSpace.YLabel.String = " ";

            for n = 1:numel(app.Data)
                app.EvaluationPanel.PlotTiles(n) = nexttile(app.EvaluationPanel.PlotSpace);
                app.EvaluationPanel.PlotTiles(n).NextPlot = 'add';
                colororder(app.EvaluationPanel.PlotTiles(1),"gem12")
                roiMasses = app.Data(n).ROIDataFileObj.ROImzVec;
                
                deltas = app.Data(n).ISdelta*1000;
                if isscalar(deltas)
                    correctionVector = repmat(deltas,1,length(roiMasses));
                else
                    correctionVector = app.Data(n).mzCorrectionFcn(roiMasses)*1000;
                end
                plot(app.EvaluationPanel.PlotTiles(1),roiMasses,correctionVector,"LineStyle","--")
                expectedMass = app.Data(n).ISMass;
                stem(app.EvaluationPanel.PlotTiles(n),expectedMass,deltas,"filled",LineStyle=":",Marker="x",MarkerSize=10)
                app.EvaluationPanel.PlotTiles(n).Children(1).DataTipTemplate.DataTipRows(1).Label="m/z:";
                app.EvaluationPanel.PlotTiles(n).Children(1).DataTipTemplate.DataTipRows(2).Label="\Delta m/z:";
                app.EvaluationPanel.PlotTiles(n).Children(2).DataTipTemplate.DataTipRows(1).Label="m/z:";
                app.EvaluationPanel.PlotTiles(n).Children(2).DataTipTemplate.DataTipRows(2).Label="\Delta m/z:";
                title(app.EvaluationPanel.PlotTiles(n),app.Data(n).GroupName)
                legend(app.EvaluationPanel.PlotTiles(n),["m/z correction function";"found mass delta"])
                xlabel(app.EvaluationPanel.PlotTiles(n),"m/z [Da]")
                ylabel(app.EvaluationPanel.PlotTiles(n),"\Deltam/z [mDa]")
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if ~isdeployed % check missing toolboxes for .mlapp Version
                AddList=matlab.addons.installedAddons;
                isInstalled = find(ismember(AddList.Identifier, {'BI','DM','SG','ST','WA','IP','DB'}));
                isEnabeled = AddList.Enabled(isInstalled);
                if numel(isInstalled)~=7 || any(isEnabeled)==false
                    text="Please install missing Toolboxes:";
                    toolboxes = AddList{:,1};
                    text = vertcat(text,toolboxes(isInstalled(~isEnabeled)));
                    uialert(app.MainWindow,text,'Missing Toolbox detected','Icon','error','CloseFcn',@alertCallback);
                    uiwait(app.MainWindow)
                    delete(app)
                end
            end
            %construct TabGroup and First Tab
            app.TabContainer = uitabgroup(app.MainWindow,"Position",[10,10,1155,685]);
            app.TabContainer.SelectionChangedFcn = @(src,event){TabSelection(app,src,event)};
            %Construct Group name Field and Select File Button
            app.groupCounter = 0;
            app.Data(1) = RawData(1);
            constructNewTab(app);
            uitab(app.TabContainer,'Title','+',Tooltip="Add additional group");
            % Default Save Location and FileName
            currentFolder = pwd;
            currentFolder = convertCharsToStrings(currentFolder);
            app.saveLocation=currentFolder+"\"+"NewProject"+".ams";
            app.colorPalette = orderedcolors("gem12");
            app.ReportTable = uifigure(Visible="off");
            delete(app.ReportTable)

            function alertCallback(~,~)
                uiresume(app.MainWindow)
            end

        end

        % Button pushed function: ImportData
        function ImportDataPushed(app, event)
            [file,path] = uigetfile({'*.ams','AriumMS File (*.ams)'},'Select a File',MultiSelect='off');
            figure(app.MainWindow);
            d = uiprogressdlg(app.MainWindow,'Title','Loading Project','Message','Please Wait','Indeterminate','on');
            if ischar(file) == true
                file = fullfile(path,file);
                app.saveLocation = file;
                %delete temporaries of all open tabs
                files = [[app.Data.RawDataFile],[app.Data.ROIDataFile]];
                id = isfile(files);
                for n = 1:numel(id)
                    if id(n) == true
                        delete(files(n))
                    end
                end
                %load data
                Importdata = load(file,'-mat');
                Importdata = Importdata.exportData;
                %store data
                app.Data = Importdata.RawData;
                if isfield(Importdata,"roiResults")
                    RebuildTempFiles(app,Importdata.previews,Importdata.roiResults);
                else
                    RebuildTempFiles(app,Importdata.previews);
                end
                app.FeatureData = Importdata.FeatureData;
                app.augmentationAssignment=removecats(Importdata.augmentationAssignment);
                app.FullFeatureData = Importdata.FullFeatureData;

                %update Parameter settings
                app.maxP = Importdata.maxP;
                app.minFold = Importdata.minFold;
                app.mzMergeTol = Importdata.mzMergeTol;
                app.mzMergeTolUnit = Importdata.mzMergeTolUnit;
                app.rtMergeTol = Importdata.rtMergeTol;
                app.dataBaseFile = Importdata.dataBaseFile;
                ActivateParameterPanels(app);
                drawnow nocallbacks limitrate
            else
                uialert(app.MainWindow,"Task aborted by user","No Data Selected","Icon","warning")
                return
            end

            %select first Group
            app.TabContainer.SelectedTab = app.Tab(1);
            app.selectedGroup = 1;
            %activate Evaluation panel if FeatureData is available
            if isempty(app.EvaluationPanel)
                app.addEvaluationPanel;
            end

            for n=1:size(app.Data,2)
                if isempty(app.Data(n).Files)
                    app.PreviewButton(n).Visible = "off";
                    app.EICButton(n).Visible = "off";
                    app.PreviewButton(n).Visible = "off";
                    app.EICButton(n).Visible = "off";
                    app.MainOptionsPanel(n).Container.Enable = "off";
                    app.ROIOptionsPanel(n).Container.Enable = "off";
                    app.IntegrationOptionsPanel(n).Container.Enable = "off";
                    app.ScalingPanel(n).Container.Enable = "off";
                    app.MSAlignPanel(n).Container.Enable = "off";
                    app.PeakAlignPanel(n).Container.Enable = "off";
                    app.BaselinePanel(n).Container.Enable = "off";
                    app.ISPanel(n).Container.Enable = "off";
                    app.GolayPanel(n).Container.Enable = "off";
                    app.AddNLossPanel(n).Container.Enable = "off";
                else    %check files
                    %check if File is still at specified location
                    if all(isfile([app.Data(n).Files;app.Data(n).BlankFiles]))
                        nScansMS1=round(mean([app.Data(n).DataInfo.NumberOfScansMS1]));
                        nScansMSn=sum([app.Data(n).DataInfo.NumberOfScansMSn]~=0);
                        freq=round(mean([app.Data(n).DataInfo.ScanFrequenceMS1]),1);
                        app.InfoLabels(1,n) = uilabel(app.Tab(n),"Text","File Infos:",...
                            "Position",[130,620,200,20],...
                            "HorizontalAlignment","left",...
                            "VerticalAlignment","center",...
                            "Tooltip",["Selected Files:";app.Data(n).Files;app.Data(n).BlankFiles]);
                        app.InfoLabels(2,n) = uilabel(app.Tab(n),"Text","Scan Rate MS1: " + freq + " Hz",...
                            "Position",[130,600,200,20],...
                            "HorizontalAlignment","left",...
                            "VerticalAlignment","center");
                        app.InfoLabels(3,n) = uilabel(app.Tab(n),"Text","Number of MS1 Scans: " + nScansMS1,...
                            "Position",[130,580,200,20],...
                            "HorizontalAlignment","left",...
                            "VerticalAlignment","center",...
                            "Tooltip","Average number of MS1 scans");
                        app.InfoLabels(4,n) = uilabel(app.Tab(n),"Text","Files with MS/MS Scans: " + nScansMSn +"/"+numel(app.Data(n).Files),...
                            "Position",[130,560,200,20],...
                            "HorizontalAlignment","left",...
                            "VerticalAlignment","center",...
                            "Tooltip","Number of files with MS/MS data");
                        %ask for File Preview
                        app.PreviewButton(n).Visible = "on";
                        app.EICButton(n).Visible = "on";
                    else
                        app.Data(n).Files = "";
                        app.PreviewButton(n).Visible = "off";
                        app.EICButton(n).Visible = "off";
                        app.MainOptionsPanel(n).Container.Enable = "off";
                        app.ROIOptionsPanel(n).Container.Enable = "off";
                        app.IntegrationOptionsPanel(n).Container.Enable = "off";
                        app.ScalingPanel(n).Container.Enable = "off";
                        app.MSAlignPanel(n).Container.Enable = "off";
                        app.PeakAlignPanel(n).Container.Enable = "off";
                        app.BaselinePanel(n).Container.Enable = "off";
                        app.ISPanel(n).Container.Enable = "off";
                        app.GolayPanel(n).Container.Enable = "off";
                        app.AddNLossPanel(n).Container.Enable = "off";
                        uialert(app.MainWindow,"Files for "+ app.Data(n).GroupName +" are missing. Reselect MS-Files .","File not found");
                    end
                end
            end
            CheckDataState(app)

            close(d)
        end

        % Button pushed function: SelectSaveLoc
        function SelectSaveLocButtonPushed(app, event)
            initFile=app.saveLocation;
            [file,path] = uiputfile('*.ams','Select Save Location and Filename',initFile);
            figure(app.MainWindow);
            if file==0 % when user cancels input set safe location to current folder and filename to workspace Variable name
                return
            else
                path = convertCharsToStrings(path);
                file = convertCharsToStrings(file);
                app.saveLocation=path+file;
            end
            app.SaveData;
        end

        % Button pushed function: CopySettingstoallGroupsButton
        function CopySettingstoallGroupsButtonPushed(app, event)
            selection = uiconfirm(app.MainWindow,'Copy settings of current Group to all Groups? (Previous Inputs will be overwritten)','Proceed?',...
                'Options',{'All Parameters','Select Parameters','Cancel'}, ...
                'DefaultOption',1,'CancelOption',3,'Icon','question');

            switch selection
                case 'Cancel'
                    return
                case 'All Parameters'
                    SelectedOptions = true(1,9);
                case 'Select Parameters'
                    SelectedOptions = false(1,9);
                    prompt = {"Choose Settings to be copied","Selected settings are applied to all groups"};
                    list = {'ROI Parameters','Peak Picking and Filter Options','Spectral Alignment Parameters','Peak Alignment Parameters','Baseline Correction Parameters','Internal Standard Options','Golay Smoothing Parameters','Scaling Options','Adduct & Neutral Loss Options'};
                    [Selectedidx,tf] = listdlg('PromptString',prompt,'SelectionMode','multiple','ListString',list,'ListSize',[220,150]);
                    figure(app.MainWindow);
                    if tf == 0
                        return
                    end
                    SelectedOptions(Selectedidx) = true;
            end
            nGroups=app.groupCounter;
            Selected=app.selectedGroup;
            CopyGroup=app.Data(Selected);
            for n=1:nGroups
                if n==Selected
                    continue
                else
                    app.Data(n).BLKSubtraction = false;

                    % ROI Block
                    if SelectedOptions(1) == true
                        app.Data(n).End = CopyGroup.End;
                        app.Data(n).Start = CopyGroup.Start;
                        app.Data(n).minroi = CopyGroup.minroi;
                        app.Data(n).mzerror = CopyGroup.mzerror;
                        app.Data(n).mzErrorUnit = CopyGroup.mzErrorUnit;
                        app.Data(n).thresh = CopyGroup.thresh;
                    end
                    % Peak Picking Block
                    if SelectedOptions(2) == true
                        app.Data(n).mzTol = CopyGroup.mzTol;
                        app.Data(n).mzTolUnit = CopyGroup.mzTolUnit;
                        app.Data(n).RTTol = CopyGroup.RTTol;
                        app.Data(n).entropyFilter = CopyGroup.entropyFilter;
                        app.Data(n).entropyStrength = CopyGroup.entropyStrength;
                        app.Data(n).minOccurence = CopyGroup.minOccurence;
                        app.Data(n).minSignalNoise = CopyGroup.minSignalNoise;
                        app.Data(n).maxWidth = CopyGroup.maxWidth;
                        app.Data(n).minWidth = CopyGroup.minWidth;
                        app.Data(n).EvaluationParameter = CopyGroup.EvaluationParameter;
                    end
                    %mz Align Block
                    if SelectedOptions(3) == true
                        app.Data(n).mzQuantil = CopyGroup.mzQuantil;
                        app.Data(n).mzCorrectionMethod = CopyGroup.mzCorrectionMethod;
                        app.Data(n).mzEstimMethod = CopyGroup.mzEstimMethod;
                        app.Data(n).MSalign = CopyGroup.MSalign;
                    end
                    %Peak Align Block
                    if SelectedOptions(4) == true
                        app.Data(n).GridSteps = CopyGroup.GridSteps;
                        app.Data(n).Iterations = CopyGroup.Iterations;
                        app.Data(n).SearchSpace = CopyGroup.SearchSpace;
                        app.Data(n).WindowSizeRatio = CopyGroup.WindowSizeRatio;
                        app.Data(n).PulseWidth = CopyGroup.PulseWidth;
                        app.Data(n).maxshiftpos = CopyGroup.maxshiftpos;
                        app.Data(n).maxshiftneg = CopyGroup.maxshiftneg;
                        app.Data(n).Peakalign = CopyGroup.Peakalign;
                    end

                    %Baseline Block
                    if SelectedOptions(5) == true
                        app.Data(n).QuantilVal = CopyGroup.QuantilVal;
                        app.Data(n).SmoothMethod = CopyGroup.SmoothMethod;
                        app.Data(n).EstimationMethod = CopyGroup.EstimationMethod;
                        app.Data(n).StepSize = CopyGroup.StepSize;
                        app.Data(n).WindowSize = CopyGroup.WindowSize;
                        app.Data(n).BaseCorr = CopyGroup.BaseCorr;
                    end
                    %IS Block
                    if SelectedOptions(6) == true
                        app.Data(n).ISOrder = CopyGroup.ISOrder;
                        app.Data(n).ISApply = CopyGroup.ISApply;
                        app.Data(n).MassCal = CopyGroup.MassCal;
                        app.Data(n).ISDat = CopyGroup.ISDat;
                        app.Data(n).numISTD = CopyGroup.numISTD;
                        app.Data(n).ISTDCorr = CopyGroup.ISTDCorr;
                    end
                    % Golay Block
                    if SelectedOptions(7) == true
                        app.Data(n).Degree = CopyGroup.Degree;
                        app.Data(n).FrameSize = CopyGroup.FrameSize;
                        app.Data(n).Smoothing = CopyGroup.Smoothing;
                    end
                    %Scaling Block
                    if SelectedOptions(8) == true
                        app.Data(n).SampScale = CopyGroup.SampScale;
                        %check size of SampScale
                        if app.Data(n).SampScale < size(app.Data(n).FileNames,1)
                            app.Data(n).SampScale(size(app.Data(n).FileNames,1),1) = 1;
                        elseif app.Data(n).SampScale > size(app.Data(n).FileNames,1)
                            app.Data(n).SampScale(size(app.Data(n).FileNames,1)+1:end,1) = [];
                        end
                        app.Data(n).GroupScale = CopyGroup.GroupScale;
                        app.Data(n).ScalingCorr = CopyGroup.ScalingCorr;
                    end
                    % Adduct and Neutral loss block
                    if SelectedOptions(9) == true
                        app.Data(n).NeutralSelectedCon = CopyGroup.NeutralSelectedCon;
                        app.Data(n).NeutralSelectedSmol = CopyGroup.NeutralSelectedSmol;
                        app.Data(n).AddSelectedNeg = CopyGroup.AddSelectedNeg;
                        app.Data(n).AddSelectedPos = CopyGroup.AddSelectedPos;
                        app.Data(n).CosSim = CopyGroup.CosSim;
                        app.Data(n).AdductFilter = CopyGroup.AdductFilter;
                        app.Data(n).IsotopeFilter = CopyGroup.IsotopeFilter;
                        app.Data(n).ContaminantFilter = CopyGroup.ContaminantFilter;
                    end

                    %Add Panels and enable
                    addMainOptions(app,n);
                    app.MainOptionsPanel(n).Container.Enable = true;
                    addROIOptions(app,n);
                    app.ROIOptionsPanel(n).Container.Enable = true;
                    addIntegrationOptions(app,n);
                    app.IntegrationOptionsPanel(n).Container.Enable = true;
                    addPeakAlignPanel(app,n)
                    app.PeakAlignPanel(n).Container.Enable = CopyGroup.Peakalign;
                    addMSAlignPanel(app,n)
                    app.MSAlignPanel(n).Container.Enable = CopyGroup.MSalign;
                    addBaselinePanel(app,n)
                    app.BaselinePanel(n).Container.Enable = CopyGroup.BaseCorr;
                    addGolayPanel(app,n)
                    app.GolayPanel(n).Container.Enable = CopyGroup.Smoothing;
                    addScalingPanel(app,n)
                    app.ScalingPanel(n).Container.Enable = CopyGroup.ScalingCorr;
                    addISPanel(app,n)
                    app.ISPanel(n).Container.Enable = CopyGroup.ISTDCorr;
                    addAddNLossPanel(app,n)
                    app.AddNLossPanel(n).Container.Enable = CopyGroup.AdductFilter;
                end
            end
            drawnow
            app.CheckReady
        end

        % Value changed function: TransformationDropDown
        function TransformationDropDownValueChanged(app, event)
            app.transformationType = app.TransformationDropDown.Value;
        end

        % Value changed function: ScalingDropDown
        function ScalingDropDownValueChanged(app, event)
            app.dataScalingType = app.ScalingDropDown.Value;
        end

        % Value changed function: maximumpValueEditField
        function maximumpValueEditFieldValueChanged(app, event)
            app.maxP = app.maximumpValueEditField.Value;
        end

        % Value changed function: minimumFoldChangeEditField
        function minimumFoldChangeEditFieldValueChanged(app, event)
            app.minFold = app.minimumFoldChangeEditField.Value;
        end

        % Button pushed function: ProcessFilesButton
        function ProcessFilesButtonPushed(app, event)
            if size(app.Data,2) > 1 && isempty(app.augmentationAssignment) || app.DefineAugmentationandReferencingButton.Enable==true && size(app.augmentationAssignment,1) ~= size(app.Data,2)
                % Augmentation Assignment not set,
                msg = {'Group augmentation or reference groups are not defined or not defined correctly.'; 'Continue?'};
                title = 'Continue?';
                selection = uiconfirm(app.MainWindow,msg,title, ...
                    'Options',{'Continue','Define Augmentation and Referencing','Cancel'}, ...
                    'DefaultOption',1,'CancelOption',3);
                switch selection
                    case 'Continue'

                    case 'Define Augmentation and Referencing'
                        ConstructAugmentationFigure(app)
                        uiwait(app.AugmentOptions.window)
                    case 'Cancel'
                        return
                end
            end
            d = uiprogressdlg(app.MainWindow,'Title','Please Wait','Message','Preparing');
            ValPerGroup = 1/(app.groupCounter+2);
            pool = gcp('nocreate');
            if isempty(pool)
                % create parallel pool when no pool present
                parpool("Processes");
            end
            clearvars pool
            tStart = tic;
            RawOut = struct('GroupName',{},'FeatIdentifiers',{},'FoundInGroup',{},'SampleNames',{},'XIC',{},'IntensityStorage',{},'RetentionTimeStorage',{},'EntropyStorage',{},'Signal2NoiseStorage',{},'DataSize',{});
            for nGroup = 1:app.groupCounter
                d.Message = "Processing Group: " + app.Data(nGroup).GroupName;
                [RawOut(nGroup),app.Data(nGroup)] = app.Data(nGroup).BatchProcess;
                d.Value = d.Value+ValPerGroup;
            end
            d.Message = "Augmenting Groups";

            %Split Output into Augmentation Assignment
            AugAssign = app.augmentationAssignment(:,1);
            AugAssign = removecats(AugAssign);
            Categories = categories(AugAssign);
            idx=AugAssign==Categories';
            %update Assignment of merged Groups

            RefAssign = app.augmentationAssignment(:,2);
            RefAssign = removecats(RefAssign,'none');
            AugAssign = strings(numel(Categories),2);
            % Build FeatureData objects
            if isempty(Categories)
                mergedRaw = RawOut;
                app.mergedAssignment = app.augmentationAssignment;
            end
            for n= 1:length(Categories)
                if sum(idx(:,n)) > 1
                    mergedRaw(n) = mergeGroups(app,RawOut(idx(:,n)));
                    Names = mergedRaw(n).GroupName;
                    mergedRaw(n).GroupName = strjoin(Names,"+");
                    mergedRaw(n).GroupName(2,1) = string(Categories(n));
                    Names = mergedRaw(n).SampleNames;
                    mergedRaw(n).SampleNames = join(Names,"+");
                    AugAssign(n,1) = convertCharsToStrings(Categories{n});
                    if ~any(isundefined(RefAssign(idx(:,n))))
                        ref = RefAssign(idx(:,n));
                        id = strcmp(ref,'none');
                        AugAssign(n,2) = ref(~id);
                    else
                        AugAssign(n,2) = "none";
                    end
                else
                    mergedRaw(n) = RawOut(idx(:,n));
                    mergedRaw(n).GroupName(2,1) = string(Categories(n));
                    app.mergedAssignment = app.augmentationAssignment;
                end
            end

            if size(mergedRaw,2) > 1 %Build fully Merged Struct
                app.FullFeatureData = BuildFullArray(app,mergedRaw);
            else
                mergedRaw.SampleNumbers = size(mergedRaw.IntensityStorage,2); %add Number of files field
                app.FullFeatureData = mergedRaw;
            end
            %Build FeatureData objects
            app.FeatureData = FeatData(app.FullFeatureData);
            app.FullFeatureData = struct();
            % calculate statistics
            app.FeatureData = app.FeatureData.RefGroupScaling(app.mergedAssignment);
            app.FeatureData = app.FeatureData.applyFeatureScaling(app.transformationType,app.dataScalingType);
            app.FeatureData = app.FeatureData.CalculateSignificance(app.hochbergFilterFlag,app.falseDiscoveryRate);

            if app.processMS2Flag == true
                d.Message = "Processing MS2";
                d.Value = d.Value+ValPerGroup;
                app = app.EvaluateMSn;
            end
            tEnd = toc(tStart);

            % build new UI Elements
            app.addEvaluationPanel;
            app.EvaluationPanel.Container.Visible = "on";
            app.FeatureScalingPanel.Visible = "on";
            app.TabContainer.Visible = "off";
            app.DataProcessingPanel.Visible = "off";
            app.SwitchView.Value = "Evaluation";
            d.Value = 1;
            CheckDataState(app);
            GenerateReport(app)
            close(d)
            text = ["Data Processing succsessful","Time to Process was "+ tEnd/60 + " Minutes"];
            uialert(app.MainWindow,text,"Success","Icon","success");
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if isfile(app.saveLocation)
                % File exists. Ask to overwrite or as new
                msg = 'Saving will overwrite previous file.';
                title = 'Confirm Save';
                selection = uiconfirm(app.MainWindow,msg,title, ...
                    'Options',{'Overwrite','Save as new','Cancel'}, ...
                    'DefaultOption',2,'CancelOption',3);
                switch selection
                    case 'Overwrite'
                        SaveData(app)
                    case 'Save as new'
                        SelectSaveLocButtonPushed(app, event)
                        SaveData(app)
                    case 'Cancel'
                        return
                end
            else
                % File does not exist. Save File as is
                SaveData(app)
            end
        end

        % Close request function: MainWindow
        function MainWindowCloseRequest(app, event)
            msg = {'Do you want to save your changes to',convertStringsToChars(app.saveLocation)};
            title = 'Confirm Close';
            selection = uiconfirm(app.MainWindow,msg,title, ...
                'Options',{'Save Project','Close without saving','Cancel'}, ...
                'DefaultOption',1,'CancelOption',3);

            switch selection
                case 'Close without saving'
                    %delete(files)
                    deleteAll(app)
                    delete(app)
                case 'Save Project'
                    SaveButtonPushed(app, event)
                    deleteAll
                    delete(app)
                case 'Cancel'
                    return
            end

            function deleteAll(app)
                uiprogressdlg(app.MainWindow,"Title","Please Wait",...
                    "Message","Closing the application","Indeterminate","on");
                poolobj = gcp('nocreate');
                delete(poolobj);
                %gather and delete temporary files
                files = [[app.Data.RawDataFile],[app.Data.ROIDataFile]];
                if ~isempty(files)
                    id = isfile(files);
                    for n = 1:numel(id)
                        if id(n) == true
                            delete(files(n))
                        end
                    end
                end
                %close AddNloss window
                for n = 1:width(app.Data)
                    if ~isempty(app.AddNLossPanel(n).window) & isgraphics(app.AddNLossPanel(n).window.UIFigure)
                        close(app.AddNLossPanel(n).window.UIFigure)
                    end
                    %close IS window
                    if ~isempty(app.ISPanel(n).window) & isgraphics(app.ISPanel(n).window)
                        close(app.ISPanel(n).window)
                    end
                    %close SampScale window
                    if ~isempty(app.ScalingPanel(n).window) & isgraphics(app.ScalingPanel(n).window)
                        close(app.ScalingPanel(n).window)
                    end
                end
                %close ResultSubDisplay
                if isgraphics(app.ResultSubDisplay)
                    close(app.ResultSubDisplay)
                end
                %close ReportTable
                if isgraphics(app.ReportTable)
                    close(app.ReportTable.Parent)
                end
                if ~isempty(app.AugmentOptions) & isgraphics(app.AugmentOptions.window)
                    close(app.AugmentOptions.window)
                end
                %close Database window
                if ~isempty(app.DataBase) & isgraphics(app.DataBase.Window)
                    close(app.DataBase.Window)
                end
                clear AddNLoss
                clear AddNLossOptions
                clear BaseCorrOptions
                clear EvaluationOptions
                clear FeatData
                clear GolayOptions
                clear IntegrationOptions
                clear ISOptions
                clear MainOptions
                clear MSAlignOptions
                clear MSDataBase
                clear NegAdducts
                clear PosAdducts
                clear Optimizer
                clear PeakAlignOptions
                clear RawData
                clear ROIOptions
                clear SampScaleOptions
                clear AriumMS
            end
        end

        % Button pushed function: RemoveSelectedGroupButton
        function RemoveSelectedGroupButtonPushed(app, event)
            Column = app.selectedGroup;
            app.groupCounter = app.groupCounter-1;
            % check if last group
            if app.groupCounter == 1
                app.RemoveSelectedGroupButton.Enable = 'off';
                app.DefineAugmentationandReferencingButton.Enable = 'off';
                app.CopySettingstoallGroupsButton.Enable = "off";
            end
            % remove row of Augmentation assignment if assignment
            % already exists
            if ~isempty(app.augmentationAssignment)
                app.augmentationAssignment(Column,:) = [];
            end
            %delete tab objects
            app.currentPlot(:,Column)=[];
            app.Data(Column) = [];
            delete(app.Tab(Column).Children)
            delete(app.Tab(Column))
            app.Tab(Column)=[];
            app.GroupNameField(Column)=[];
            app.MainOptionsPanel(Column)=[];
            app.ROIOptionsPanel(Column)=[];
            app.IntegrationOptionsPanel(Column)=[];
            app.ISPanel(Column)=[];
            app.PeakAlignPanel(Column)=[];
            app.MSAlignPanel(Column)=[];
            app.BaselinePanel(Column)=[];
            app.GolayPanel(Column)=[];
            app.AddNLossPanel(Column)=[];
            app.ScalingPanel(Column)=[];
            app.InfoLabels(:,Column)=[];
            app.SelectFilesButton(Column)=[];
            app.PreviewButton(Column)=[];
            app.EICButton(Column)=[];
            app.PreviewAxes(Column)=[];
            app.PreviewTiled(Column)=[];
            % update tab identifier and callbacks of all tabs
            for n=1:size(app.Tab,2)
                % Tab Identifier
                app.Tab(n).UserData = n;
                UpdateCallbacks(app,n);
            end
            % update selected Group
            NewSelection=Column-1;
            if NewSelection < 1
                NewSelection = 1;
            end
            app.TabContainer.SelectedTab=app.Tab(NewSelection);
            app.selectedGroup=app.TabContainer.SelectedTab.UserData;
        end

        % Button pushed function: DefineAugmentationandReferencingButton
        function DefineAugmentationandReferencingButtonPushed(app, event)
            ConstructAugmentationFigure(app);
        end

        % Value changed function: SwitchView
        function SwitchViewValueChanged(app, event)
            value = app.SwitchView.Value;
            switch value
                case "Processing"
                    if size(app.EvaluationPanel,1) == 1
                        app.EvaluationPanel.Container.Visible = "off";
                    end
                    app.FeatureScalingPanel.Visible = "off";
                    app.TabContainer.Visible = "on";
                    app.DataProcessingPanel.Visible = "on";

                case "Evaluation"
                    if size(app.EvaluationPanel,1) == 1
                        app.EvaluationPanel.Container.Visible = "on";
                    else
                        app.EvaluationPanel = EvaluationOptions(app);
                    end
                    app.TabContainer.Visible = "off";
                    app.FeatureScalingPanel.Visible = "on";
                    app.DataProcessingPanel.Visible = "off";
            end
        end

        % Value changed function: Hochberg
        function HochbergValueChanged(app, event)
            app.hochbergFilterFlag = app.Hochberg.Value;
            app.FalseDiscoveryRateLabel.Visible = app.hochbergFilterFlag;
            app.FDR.Visible = app.hochbergFilterFlag;
        end

        % Value changed function: FDR
        function FDRValueChanged(app, event)
            app.falseDiscoveryRate = app.FDR.Value/100;
        end

        % Button pushed function: ApplyChangesButton
        function ApplyChangesButtonPushed(app, event)
            % reapply scaling
            app.FeatureData = app.FeatureData.RefGroupScaling(app.mergedAssignment);
            app.FeatureData = app.FeatureData.applyFeatureScaling(app.transformationType,app.dataScalingType);
            % calculate new Statistics
            app.FeatureData = app.FeatureData.CalculateSignificance(app.hochbergFilterFlag,app.falseDiscoveryRate);
            if isvalid(app.ReportTable)
                close(app.ReportTable.Parent)
                app.GenerateReport
            else
                app.GenerateReport
            end

        end

        % Button pushed function: DEBUGButton
        function DEBUGButtonPushed(app, event)
            assignin("base","app",app)
        end

        % Value changed function: ProcessMS2DataCheckBox
        function ProcessMS2DataCheckBoxValueChanged(app, event)
            app.processMS2Flag = app.ProcessMS2DataCheckBox.Value;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainWindow = uifigure('Visible', 'off');
            app.MainWindow.Name = 'AriumMS';
            app.MainWindow.CloseRequestFcn = createCallbackFcn(app, @MainWindowCloseRequest, true);
            app.AriumMSLabel = uilabel(app.MainWindow);
            app.AriumMSLabel.Text = 'AriumMS';
            app.Label = uilabel(app.MainWindow);
            app.Label.Text = '1.2.01';
            app.GeneralOptionsPanel = uipanel(app.MainWindow);
            app.GeneralOptionsPanel.Title = 'General Options';
            app.ImportData = uibutton(app.GeneralOptionsPanel, 'push');
            app.ImportData.ButtonPushedFcn = createCallbackFcn(app, @ImportDataPushed, true);
            app.ImportData.Text = 'Import Project';
            app.SelectSaveLoc = uibutton(app.GeneralOptionsPanel, 'push');
            app.SelectSaveLoc.ButtonPushedFcn = createCallbackFcn(app, @SelectSaveLocButtonPushed, true);
            app.SelectSaveLoc.Text = 'Select Save Location';
            app.SaveButton = uibutton(app.GeneralOptionsPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save Project';
            app.FeatureScalingPanel = uipanel(app.MainWindow);
            app.FeatureScalingPanel.Title = 'Feature Scaling';
            app.FeatureScalingPanel.Visible = 'off';
            app.ScalingDropDownLabel = uilabel(app.FeatureScalingPanel);
            app.ScalingDropDownLabel.Text = 'Scaling';
            app.ScalingDropDown = uidropdown(app.FeatureScalingPanel);
            app.ScalingDropDown.Items = {'none', 'Centering', 'Auto', 'Pareto', 'Vast', 'Range', 'Level'};
            app.ScalingDropDown.ItemsData = {'none', 'Centering', 'Auto', 'Pareto', 'Vast', 'Range', 'Level'};
            app.ScalingDropDown.ValueChangedFcn = createCallbackFcn(app, @ScalingDropDownValueChanged, true);
            app.ScalingDropDown.Value = 'none';
            app.TransformationDropDownLabel = uilabel(app.FeatureScalingPanel);
            app.TransformationDropDownLabel.Text = 'Transformation';
            app.TransformationDropDown = uidropdown(app.FeatureScalingPanel);
            app.TransformationDropDown.Items = {'none', 'Natural log', 'log 10', 'Power', 'Cube Root', 'Reciprocal'};
            app.TransformationDropDown.ItemsData = {'none', 'logn', 'log10', 'power', 'cube', 'reciprocal'};
            app.TransformationDropDown.ValueChangedFcn = createCallbackFcn(app, @TransformationDropDownValueChanged, true);
            app.TransformationDropDown.Value = 'none';
            app.maximumpValueEditFieldLabel = uilabel(app.FeatureScalingPanel);
            app.maximumpValueEditFieldLabel.Text = {'maximum'; 'p-Value'};
            app.maximumpValueEditField = uieditfield(app.FeatureScalingPanel, 'numeric');
            app.maximumpValueEditField.Limits = [0 1];
            app.maximumpValueEditField.ValueChangedFcn = createCallbackFcn(app, @maximumpValueEditFieldValueChanged, true);
            app.maximumpValueEditField.Value = 0.05;
            app.minimumFoldChangeEditFieldLabel = uilabel(app.FeatureScalingPanel);
            app.minimumFoldChangeEditFieldLabel.Text = {'minimum'; 'Fold Change'};
            app.minimumFoldChangeEditField = uieditfield(app.FeatureScalingPanel, 'numeric');
            app.minimumFoldChangeEditField.Limits = [0 Inf];
            app.minimumFoldChangeEditField.ValueChangedFcn = createCallbackFcn(app, @minimumFoldChangeEditFieldValueChanged, true);
            app.minimumFoldChangeEditField.Value = 2;
            app.ApplyChangesButton = uibutton(app.FeatureScalingPanel, 'push');
            app.ApplyChangesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyChangesButtonPushed, true);
            app.ApplyChangesButton.Enable = 'off';
            app.ApplyChangesButton.Text = 'Apply Changes';
            app.Hochberg = uicheckbox(app.FeatureScalingPanel);
            app.Hochberg.ValueChangedFcn = createCallbackFcn(app, @HochbergValueChanged, true);
            app.Hochberg.Text = 'Benjamini Hochberg Filter';
            app.FalseDiscoveryRateLabel = uilabel(app.FeatureScalingPanel);
            app.FalseDiscoveryRateLabel.Visible = 'off';
            app.FalseDiscoveryRateLabel.Text = {'False Discovery '; 'Rate '};
            app.FDR = uieditfield(app.FeatureScalingPanel, 'numeric');
            app.FDR.Limits = [0 100];
            app.FDR.ValueDisplayFormat = '%.1f %%';
            app.FDR.ValueChangedFcn = createCallbackFcn(app, @FDRValueChanged, true);
            app.FDR.Visible = 'off';
            app.FDR.Value = 10;
            app.SwitchViewLabel = uilabel(app.MainWindow);
            app.SwitchViewLabel.Text = 'Switch View';
            app.SwitchView = uiswitch(app.MainWindow, 'slider');
            app.SwitchView.Items = {'Processing', 'Evaluation'};
            app.SwitchView.ValueChangedFcn = createCallbackFcn(app, @SwitchViewValueChanged, true);
            app.SwitchView.Value = 'Processing';
            app.DataProcessingPanel = uipanel(app.MainWindow);
            app.DataProcessingPanel.Title = 'Data Processing';
            app.DefineAugmentationandReferencingButton = uibutton(app.DataProcessingPanel, 'push');
            app.DefineAugmentationandReferencingButton.ButtonPushedFcn = createCallbackFcn(app, @DefineAugmentationandReferencingButtonPushed, true);
            app.DefineAugmentationandReferencingButton.Enable = 'off';
            app.DefineAugmentationandReferencingButton.Text = {'Define Augmentation '; 'and Referencing'};
            app.CopySettingstoallGroupsButton = uibutton(app.DataProcessingPanel, 'push');
            app.CopySettingstoallGroupsButton.ButtonPushedFcn = createCallbackFcn(app, @CopySettingstoallGroupsButtonPushed, true);
            app.CopySettingstoallGroupsButton.Text = {'Copy Settings '; 'to all Groups'};
            app.RemoveSelectedGroupButton = uibutton(app.DataProcessingPanel, 'push');
            app.RemoveSelectedGroupButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveSelectedGroupButtonPushed, true);
            app.RemoveSelectedGroupButton.Text = {'Remove '; 'Selected Group'};
            app.ProcessFilesButton = uibutton(app.DataProcessingPanel, 'push');
            app.ProcessFilesButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessFilesButtonPushed, true);
            app.ProcessFilesButton.Enable = 'off';
            app.ProcessFilesButton.Text = {'Process'; 'Files'};
            app.ProcessMS2DataCheckBox = uicheckbox(app.DataProcessingPanel);
            app.ProcessMS2DataCheckBox.ValueChangedFcn = createCallbackFcn(app, @ProcessMS2DataCheckBoxValueChanged, true);
            app.ProcessMS2DataCheckBox.Text = 'Process MS2 Data';
            app.DEBUGButton = uibutton(app.MainWindow, 'push');
            app.DEBUGButton.ButtonPushedFcn = createCallbackFcn(app, @DEBUGButtonPushed, true);
            app.DEBUGButton.Enable = 'off';
            app.DEBUGButton.Visible = 'off';
            app.DEBUGButton.Text = 'DEBUG';
            % Show the figure after all components are created
            app.MainWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AriumMS

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.MainWindow)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.MainWindow)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainWindow)
        end
    end
end